classdef diagscatter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        handlescattervisualoptioninspectionUIFigure  matlab.ui.Figure
        GridLayout4                    matlab.ui.container.GridLayout
        plotoptionsPanel               matlab.ui.container.Panel
        contoursEditField              matlab.ui.control.NumericEditField
        contoursEditFieldLabel         matlab.ui.control.Label
        colormapDropDown               matlab.ui.control.DropDown
        colormapDropDownLabel          matlab.ui.control.Label
        plotscaleSwitch                matlab.ui.control.Switch
        plotscaleSwitchLabel           matlab.ui.control.Label
        secondorderscatterplotsPanel   matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        secram                         matlab.ui.control.UIAxes
        secray                         matlab.ui.control.UIAxes
        secondorderscatterPanel        matlab.ui.container.Panel
        GridLayout7                    matlab.ui.container.GridLayout
        RamanLabel_2                   matlab.ui.control.Label
        RayleighLabel_2                matlab.ui.control.Label
        rm2w                           matlab.ui.control.Spinner
        ry2w                           matlab.ui.control.Spinner
        widenLabel_2                   matlab.ui.control.Label
        rm2n                           matlab.ui.control.Spinner
        ry2n                           matlab.ui.control.Spinner
        narrowLabel_2                  matlab.ui.control.Label
        cut_ry2                        matlab.ui.control.CheckBox
        int_ry2                        matlab.ui.control.CheckBox
        cut_rm2                        matlab.ui.control.CheckBox
        int_rm2                        matlab.ui.control.CheckBox
        cutLabel_2                     matlab.ui.control.Label
        interpLabel_2                  matlab.ui.control.Label
        belowLabel_2                   matlab.ui.control.Label
        aboveLabel_2                   matlab.ui.control.Label
        ry2b                           matlab.ui.control.Spinner
        rm2b                           matlab.ui.control.Spinner
        ry2a                           matlab.ui.control.Spinner
        rm2a                           matlab.ui.control.Spinner
        firstorderscatterPanel         matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        RamanLabel                     matlab.ui.control.Label
        RayleighLabel                  matlab.ui.control.Label
        rm1w                           matlab.ui.control.Spinner
        ry1w                           matlab.ui.control.Spinner
        widenLabel                     matlab.ui.control.Label
        rm1n                           matlab.ui.control.Spinner
        ry1n                           matlab.ui.control.Spinner
        narrowLabel                    matlab.ui.control.Label
        rm1a                           matlab.ui.control.Spinner
        ry1a                           matlab.ui.control.Spinner
        rm1b                           matlab.ui.control.Spinner
        ry1b                           matlab.ui.control.Spinner
        aboveLabel                     matlab.ui.control.Label
        belowLabel                     matlab.ui.control.Label
        interpLabel                    matlab.ui.control.Label
        cutLabel                       matlab.ui.control.Label
        int_rm1                        matlab.ui.control.CheckBox
        cut_rm1                        matlab.ui.control.CheckBox
        int_ry1                        matlab.ui.control.CheckBox
        cut_ry1                        matlab.ui.control.CheckBox
        miscellaneousoptionsPanel      matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        InterpolationSwitchLabel       matlab.ui.control.Label
        InterpolationSwitch            matlab.ui.control.RockerSwitch
        nmtozeroLabel                  matlab.ui.control.Label
        d2zero                         matlab.ui.control.Spinner
        n2z                            matlab.ui.control.CheckBox
        firstorderscatterplotsPanel    matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        firstram                       matlab.ui.control.UIAxes
        firstray                       matlab.ui.control.UIAxes
        excitationemissionmatrixPanel  matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        eem                            matlab.ui.control.UIAxes
        samplenavitationPanel          matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        exeplot_3                      matlab.ui.control.Button
        exeplot_2                      matlab.ui.control.Button
        testsettings                   matlab.ui.control.Button
        SampleDropDownLabel            matlab.ui.control.Label
        sampleselection                matlab.ui.control.DropDown
    end

    properties (Access = public)
        data=struct;
        opt=struct;
    end
    methods (Access = private)

        function [nanout,iout]=labeler(app,nanin,iin,x,y,type,below,above,iswitch,cutswitch,nrrowrate)
            nanout=nanin;
            iout=iin;
            syncx=nan(numel(x),numel(y));
            % switch-case for different types (d2zero immediately returns)

            switch type
                case 'ray1'
                    below = linspace(below,below-((x(end)-x(1))/50)*nrrowrate,numel(y));
                    above = linspace(above,above-((x(end)-x(1))/50)*nrrowrate,numel(y));

                    for n=1:numel(x)
                        syncx(n,:) = y-x(n);
                    end
                case 'ray2'
                    below = linspace(below,below-((x(end)-x(1))/50)*nrrowrate,numel(y));
                    above = linspace(above,above-((x(end)-x(1))/50)*nrrowrate,numel(y));

                    for n=1:numel(x)
                        syncx(n,:) = y-2*x(n);
                    end
                case 'ram1'
                    below = linspace(below,below-((x(end)-x(1))/50)*nrrowrate,numel(y));
                    above = linspace(above,above-((x(end)-x(1))/50)*nrrowrate,numel(y));

                    for n=1:numel(x)
                        syncx(n,:)= y -(1e7*((1e7)/(x(n))-3382)^-1);
                    end

                case 'ram2'
                    below = linspace(below,below-((x(end)-x(1))/50)*nrrowrate,numel(y));
                    above = linspace(above,above-((x(end)-x(1))/50)*nrrowrate,numel(y));

                    for n=1:numel(x)
                        syncx(n,:) = y - ((1e7*((1e7)/(x(n))-3382)^-1)*2);
                    end
                case 'd2zero'
                    for n=1:numel(x)
                        syncx=y-x(n);
                        nanout(syncx<=-below,n)=true;
                    end
                    return
                otherwise
                    error('Input to ''type'' must be one of these: ray1 ray2 ram1 ram2 d2zero')
            end

            for n=1:numel(x)
                if cutswitch
                    nanout(syncx(n,:)>=-below&syncx(n,:)<=above,n)=true;
                else
                    nanout(syncx(n,:)>=-below&syncx(n,:)<=above,n)=false;
                end
                if iswitch
                    iout(syncx(n,:)>=-below&syncx(n,:)<=above,n)=2;
                else
                    iout(syncx(n,:)>=-below&syncx(n,:)<=above,n)=3;
                end
            end

        end


        function [dataout] = synchronizeeem(app,data,type,mode)


            switch mode
                case 'apply'
                    sign=@(x,y) minus(x,y);
                case 'reverse'
                    sign=@(x,y) plus(x,y);
            end

            switch type
                case 'ray1'
                    for n=1:data.nEx
                        em(n,:) = sign(data.Em,data.Ex(n));
                    end
                case 'ray2'
                    for n=1:data.nEx
                        em(n,:) = sign(data.Em,data.Ex(n)*2);
                    end
                case 'ram1'
                    for n=1:data.nEx
                        em(n,:) = sign(data.Em,(1e7*((1e7)/(data.Ex(n))-3382)^-1));
                    end
                case 'ram2'
                    for n=1:data.nEx
                        em(n,:)=data.Em - ((1e7*((1e7)/(data.Ex(n))-3382)^-1)*2);
                    end
                otherwise
                    error('Input to ''type'' must be one of these: ray1 ray2 ram1 ram2')
            end

            minmax=[-50 floor(min(em(:,end)))];
            minmax=[-200 200];

            minmax=[min(em(:)) max(em(:))];


            % minmax=[-30 30];

            dem=app.rcvec(minmax(1):mean(diff(data.Em)):minmax(2),'column');

            Xn=nan(data.nSample,numel(dem),data.nEx);

            for n=1:data.nSample
                for i=1:data.nEx
                    Xn(n,:,i)=interp1(em(i,:),...
                        squeeze(data.X(n,:,i)),...
                        dem);
                end
            end

            dataout=data;
            dataout.Em=dem;
            dataout.nEm=numel(dem);
            %dataout.Xorg=data.X;
            dataout.X=Xn;
            % eemreview(dataout,'hold',true)

        end

        function Xout=interpolatetheeem(app,Xin,method)
            Xout=zeros(size(Xin,1),size(Xin,2),size(Xin,3));


            switch method
                case 'inpaint'
                    for n=1:size(Xin,1)
                        x=squeeze(Xin(n,:,:));
                        x=inpaintn(x,500);
                        Xout(n,:,:)=x;
                    end
                case 'fillmissing'
                    for n=1:size(Xin,1)
                        x=squeeze(Xin(n,:,:));
                        x=fillmissing(x,'pchip',1,'EndValues','nearest');
                        Xout(n,:,:)=x;
                    end
            end

        end


        function vout = rcvec(~,v,rc)
            % Make row or column vector
            % v: vector
            % rc: either 'row' ([1:5])or 'column' ([1:5]')
            sz=size(v);
            if ~any(sz==1)
                error('Input is not a vector')
            end

            switch rc
                case 'row'
                    if ~[sz(1)<sz(2)]
                        vout=v';
                    else
                        vout=v;
                    end
                case 'column'
                    if ~[sz(1)>sz(2)]
                        vout=v';
                    else
                        vout=v;
                    end
                otherwise
                    error('Input ''rc'' not recognized. Options are: ''row'' and ''column''.')
            end


        end
        function neighbors_list=identify_neighbors(~,n,m,nan_list,talks_to)
            % identify_neighbors: identifies all the neighbors of
            %   those nodes in nan_list, not including the nans
            %   themselves
            %
            % arguments (input):
            %  n,m - scalar - [n,m]=size(A), where A is the
            %      array to be interpolated
            %  nan_list - array - list of every nan element in A
            %      nan_list(i,1) == linear index of i'th nan element
            %      nan_list(i,2) == row index of i'th nan element
            %      nan_list(i,3) == column index of i'th nan element
            %  talks_to - px2 array - defines which nodes communicate
            %      with each other, i.e., which nodes are neighbors.
            %
            %      talks_to(i,1) - defines the offset in the row
            %                      dimension of a neighbor
            %      talks_to(i,2) - defines the offset in the column
            %                      dimension of a neighbor
            %
            %      For example, talks_to = [-1 0;0 -1;1 0;0 1]
            %      means that each node talks only to its immediate
            %      neighbors horizontally and vertically.
            %
            % arguments(output):
            %  neighbors_list - array - list of all neighbors of
            %      all the nodes in nan_list
            if ~isempty(nan_list)
                % use the definition of a neighbor in talks_to
                nan_count=size(nan_list,1);
                talk_count=size(talks_to,1);

                nn=zeros(nan_count*talk_count,2);
                j=[1,nan_count];
                for i=1:talk_count
                    nn(j(1):j(2),:)=nan_list(:,2:3) + ...
                        repmat(talks_to(i,:),nan_count,1);
                    j=j+nan_count;
                end

                % drop those nodes which fall outside the bounds of the
                % original array
                L = (nn(:,1)<1)|(nn(:,1)>n)|(nn(:,2)<1)|(nn(:,2)>m);
                nn(L,:)=[];

                % form the same format 3 column array as nan_list
                neighbors_list=[sub2ind([n,m],nn(:,1),nn(:,2)),nn];

                % delete replicates in the neighbors list
                neighbors_list=unique(neighbors_list,'rows');

                % and delete those which are also in the list of NaNs.
                neighbors_list=setdiff(neighbors_list,nan_list,'rows');

            else
                neighbors_list=[];
            end
        end

        function B=inpaint_nans(app,A,method)
            % INPAINT_NANS: in-paints over nans in an array
            % usage: B=INPAINT_NANS(A)          % default method
            % usage: B=INPAINT_NANS(A,method)   % specify method used
            %
            % Solves approximation to one of several pdes to
            % interpolate and extrapolate holes in an array
            %
            % arguments (input):
            %   A - nxm array with some NaNs to be filled in
            %
            %   method - (OPTIONAL) scalar numeric flag - specifies
            %       which approach (or physical metaphor to use
            %       for the interpolation.) All methods are capable
            %       of extrapolation, some are better than others.
            %       There are also speed differences, as well as
            %       accuracy differences for smooth surfaces.
            %
            %       methods {0,1,2} use a simple plate metaphor.
            %       method  3 uses a better plate equation,
            %                 but may be much slower and uses
            %                 more memory.
            %       method  4 uses a spring metaphor.
            %       method  5 is an 8 neighbor average, with no
            %                 rationale behind it compared to the
            %                 other methods. I do not recommend
            %                 its use.
            %
            %       method == 0 --> (DEFAULT) see method 1, but
            %         this method does not build as large of a
            %         linear system in the case of only a few
            %         NaNs in a large array.
            %         Extrapolation behavior is linear.
            %
            %       method == 1 --> simple approach, applies del^2
            %         over the entire array, then drops those parts
            %         of the array which do not have any contact with
            %         NaNs. Uses a least squares approach, but it
            %         does not modify known values.
            %         In the case of small arrays, this method is
            %         quite fast as it does very little extra work.
            %         Extrapolation behavior is linear.
            %
            %       method == 2 --> uses del^2, but solving a direct
            %         linear system of equations for nan elements.
            %         This method will be the fastest possible for
            %         large systems since it uses the sparsest
            %         possible system of equations. Not a least
            %         squares approach, so it may be least robust
            %         to noise on the boundaries of any holes.
            %         This method will also be least able to
            %         interpolate accurately for smooth surfaces.
            %         Extrapolation behavior is linear.
            %
            %         Note: method 2 has problems in 1-d, so this
            %         method is disabled for vector inputs.
            %
            %       method == 3 --+ See method 0, but uses del^4 for
            %         the interpolating operator. This may result
            %         in more accurate interpolations, at some cost
            %         in speed.
            %
            %       method == 4 --+ Uses a spring metaphor. Assumes
            %         springs (with a nominal length of zero)
            %         connect each node with every neighbor
            %         (horizontally, vertically and diagonally)
            %         Since each node tries to be like its neighbors,
            %         extrapolation is as a constant function where
            %         this is consistent with the neighboring nodes.
            %
            %       method == 5 --+ See method 2, but use an average
            %         of the 8 nearest neighbors to any element.
            %         This method is NOT recommended for use.
            %
            %
            % arguments (output):
            %   B - nxm array with NaNs replaced
            %
            %
            % Example:
            %  [x,y] = meshgrid(0:.01:1);
            %  z0 = exp(x+y);
            %  znan = z0;
            %  znan(20:50,40:70) = NaN;
            %  znan(30:90,5:10) = NaN;
            %  znan(70:75,40:90) = NaN;
            %
            %  z = inpaint_nans(znan);
            %
            %
            % See also: griddata, interp1
            %
            % Author: John D'Errico
            % e-mail address: woodchips@rochester.rr.com
            % Release: 2
            % Release date: 4/15/06
            % I always need to know which elements are NaN,
            % and what size the array is for any method
            [n,m]=size(A);
            A=A(:);
            nm=n*m;
            k=isnan(A(:));
            % list the nodes which are known, and which will
            % be interpolated
            nan_list=find(k);
            known_list=find(~k);
            % how many nans overall
            nan_count=length(nan_list);
            % convert NaN indices to (r,c) form
            % nan_list==find(k) are the unrolled (linear) indices
            % (row,column) form
            [nr,nc]=ind2sub([n,m],nan_list);
            % both forms of index in one array:
            % column 1 == unrolled index
            % column 2 == row index
            % column 3 == column index
            nan_list=[nan_list,nr,nc];
            % supply default method
            if (nargin<2) || isempty(method)
                method = 0;
            elseif ~ismember(method,0:5)
                error 'If supplied, method must be one of: {0,1,2,3,4,5}.'
            end
            % for different methods
            switch method
                case 0
                    % The same as method == 1, except only work on those
                    % elements which are NaN, or at least touch a NaN.

                    % is it 1-d or 2-d?
                    if (m == 1) || (n == 1)
                        % really a 1-d case
                        work_list = nan_list(:,1);
                        work_list = unique([work_list;work_list - 1;work_list + 1]);
                        work_list(work_list <= 1) = [];
                        work_list(work_list >= nm) = [];
                        nw = numel(work_list);

                        u = (1:nw)';
                        fda = sparse(repmat(u,1,3),bsxfun(@plus,work_list,-1:1), ...
                            repmat([1 -2 1],nw,1),nw,nm);
                    else
                        % a 2-d case

                        % horizontal and vertical neighbors only
                        talks_to = [-1 0;0 -1;1 0;0 1];
                        neighbors_list=app.identify_neighbors(n,m,nan_list,talks_to);

                        % list of all nodes we have identified
                        all_list=[nan_list;neighbors_list];

                        % generate sparse array with second partials on row
                        % variable for each element in either list, but only
                        % for those nodes which have a row index > 1 or < n
                        L = find((all_list(:,2) > 1) & (all_list(:,2) < n));
                        nl=length(L);
                        if nl>0
                            fda=sparse(repmat(all_list(L,1),1,3), ...
                                repmat(all_list(L,1),1,3)+repmat([-1 0 1],nl,1), ...
                                repmat([1 -2 1],nl,1),nm,nm);
                        else
                            fda=spalloc(n*m,n*m,size(all_list,1)*5);
                        end

                        % 2nd partials on column index
                        L = find((all_list(:,3) > 1) & (all_list(:,3) < m));
                        nl=length(L);
                        if nl>0
                            fda=fda+sparse(repmat(all_list(L,1),1,3), ...
                                repmat(all_list(L,1),1,3)+repmat([-n 0 n],nl,1), ...
                                repmat([1 -2 1],nl,1),nm,nm);
                        end
                    end

                    % eliminate knowns
                    rhs=-fda(:,known_list)*A(known_list);
                    k=find(any(fda(:,nan_list(:,1)),2));

                    % and solve...
                    B=A;
                    B(nan_list(:,1))=fda(k,nan_list(:,1))\rhs(k);

                case 1
                    % least squares approach with del^2. Build system
                    % for every array element as an unknown, and then
                    % eliminate those which are knowns.
                    % Build sparse matrix approximating del^2 for
                    % every element in A.

                    % is it 1-d or 2-d?
                    if (m == 1) || (n == 1)
                        % a 1-d case
                        u = (1:(nm-2))';
                        fda = sparse(repmat(u,1,3),bsxfun(@plus,u,0:2), ...
                            repmat([1 -2 1],nm-2,1),nm-2,nm);
                    else
                        % a 2-d case

                        % Compute finite difference for second partials
                        % on row variable first
                        [i,j]=ndgrid(2:(n-1),1:m);
                        ind=i(:)+(j(:)-1)*n;
                        np=(n-2)*m;
                        fda=sparse(repmat(ind,1,3),[ind-1,ind,ind+1], ...
                            repmat([1 -2 1],np,1),n*m,n*m);

                        % now second partials on column variable
                        [i,j]=ndgrid(1:n,2:(m-1));
                        ind=i(:)+(j(:)-1)*n;
                        np=n*(m-2);
                        fda=fda+sparse(repmat(ind,1,3),[ind-n,ind,ind+n], ...
                            repmat([1 -2 1],np,1),nm,nm);
                    end

                    % eliminate knowns
                    rhs=-fda(:,known_list)*A(known_list);
                    k=find(any(fda(:,nan_list),2));

                    % and solve...
                    B=A;
                    B(nan_list(:,1))=fda(k,nan_list(:,1))\rhs(k);

                case 2
                    % Direct solve for del^2 BVP across holes
                    % generate sparse array with second partials on row
                    % variable for each nan element, only for those nodes
                    % which have a row index > 1 or < n

                    % is it 1-d or 2-d?
                    if (m == 1) || (n == 1)
                        % really just a 1-d case
                        error('Method 2 has problems for vector input. Please use another method.')

                    else
                        % a 2-d case
                        L = find((nan_list(:,2) > 1) & (nan_list(:,2) < n));
                        nl=length(L);
                        if nl>0
                            fda=sparse(repmat(nan_list(L,1),1,3), ...
                                repmat(nan_list(L,1),1,3)+repmat([-1 0 1],nl,1), ...
                                repmat([1 -2 1],nl,1),n*m,n*m);
                        else
                            fda=spalloc(n*m,n*m,size(nan_list,1)*5);
                        end

                        % 2nd partials on column index
                        L = find((nan_list(:,3) > 1) & (nan_list(:,3) < m));
                        nl=length(L);
                        if nl>0
                            fda=fda+sparse(repmat(nan_list(L,1),1,3), ...
                                repmat(nan_list(L,1),1,3)+repmat([-n 0 n],nl,1), ...
                                repmat([1 -2 1],nl,1),n*m,n*m);
                        end

                        % fix boundary conditions at extreme corners
                        % of the array in case there were nans there
                        if ismember(1,nan_list(:,1))
                            fda(1,[1 2 n+1])=[-2 1 1];
                        end
                        if ismember(n,nan_list(:,1))
                            fda(n,[n, n-1,n+n])=[-2 1 1];
                        end
                        if ismember(nm-n+1,nan_list(:,1))
                            fda(nm-n+1,[nm-n+1,nm-n+2,nm-n])=[-2 1 1];
                        end
                        if ismember(nm,nan_list(:,1))
                            fda(nm,[nm,nm-1,nm-n])=[-2 1 1];
                        end

                        % eliminate knowns
                        rhs=-fda(:,known_list)*A(known_list);

                        % and solve...
                        B=A;
                        k=nan_list(:,1);
                        B(k)=fda(k,k)\rhs(k);

                    end

                case 3
                    % The same as method == 0, except uses del^4 as the
                    % interpolating operator.

                    % del^4 template of neighbors
                    talks_to = [-2 0;-1 -1;-1 0;-1 1;0 -2;0 -1; ...
                        0 1;0 2;1 -1;1 0;1 1;2 0];
                    neighbors_list=app.identify_neighbors(n,m,nan_list,talks_to);

                    % list of all nodes we have identified
                    all_list=[nan_list;neighbors_list];

                    % generate sparse array with del^4, but only
                    % for those nodes which have a row & column index
                    % >= 3 or <= n-2
                    L = find( (all_list(:,2) >= 3) & ...
                        (all_list(:,2) <= (n-2)) & ...
                        (all_list(:,3) >= 3) & ...
                        (all_list(:,3) <= (m-2)));
                    nl=length(L);
                    if nl>0
                        % do the entire template at once
                        fda=sparse(repmat(all_list(L,1),1,13), ...
                            repmat(all_list(L,1),1,13) + ...
                            repmat([-2*n,-n-1,-n,-n+1,-2,-1,0,1,2,n-1,n,n+1,2*n],nl,1), ...
                            repmat([1 2 -8 2 1 -8 20 -8 1 2 -8 2 1],nl,1),nm,nm);
                    else
                        fda=spalloc(n*m,n*m,size(all_list,1)*5);
                    end

                    % on the boundaries, reduce the order around the edges
                    L = find((((all_list(:,2) == 2) | ...
                        (all_list(:,2) == (n-1))) & ...
                        (all_list(:,3) >= 2) & ...
                        (all_list(:,3) <= (m-1))) | ...
                        (((all_list(:,3) == 2) | ...
                        (all_list(:,3) == (m-1))) & ...
                        (all_list(:,2) >= 2) & ...
                        (all_list(:,2) <= (n-1))));
                    nl=length(L);
                    if nl>0
                        fda=fda+sparse(repmat(all_list(L,1),1,5), ...
                            repmat(all_list(L,1),1,5) + ...
                            repmat([-n,-1,0,+1,n],nl,1), ...
                            repmat([1 1 -4 1 1],nl,1),nm,nm);
                    end

                    L = find( ((all_list(:,2) == 1) | ...
                        (all_list(:,2) == n)) & ...
                        (all_list(:,3) >= 2) & ...
                        (all_list(:,3) <= (m-1)));
                    nl=length(L);
                    if nl>0
                        fda=fda+sparse(repmat(all_list(L,1),1,3), ...
                            repmat(all_list(L,1),1,3) + ...
                            repmat([-n,0,n],nl,1), ...
                            repmat([1 -2 1],nl,1),nm,nm);
                    end

                    L = find( ((all_list(:,3) == 1) | ...
                        (all_list(:,3) == m)) & ...
                        (all_list(:,2) >= 2) & ...
                        (all_list(:,2) <= (n-1)));
                    nl=length(L);
                    if nl>0
                        fda=fda+sparse(repmat(all_list(L,1),1,3), ...
                            repmat(all_list(L,1),1,3) + ...
                            repmat([-1,0,1],nl,1), ...
                            repmat([1 -2 1],nl,1),nm,nm);
                    end

                    % eliminate knowns
                    rhs=-fda(:,known_list)*A(known_list);
                    k=find(any(fda(:,nan_list(:,1)),2));

                    % and solve...
                    B=A;
                    B(nan_list(:,1))=fda(k,nan_list(:,1))\rhs(k);

                case 4
                    % Spring analogy
                    % interpolating operator.

                    % list of all springs between a node and a horizontal
                    % or vertical neighbor
                    hv_list=[-1 -1 0;1 1 0;-n 0 -1;n 0 1];
                    hv_springs=[];
                    for i=1:4
                        hvs=nan_list+repmat(hv_list(i,:),nan_count,1);
                        k=(hvs(:,2)>=1) & (hvs(:,2)<=n) & (hvs(:,3)>=1) & (hvs(:,3)<=m);
                        hv_springs=[hv_springs;[nan_list(k,1),hvs(k,1)]];
                    end
                    % delete replicate springs
                    hv_springs=unique(sort(hv_springs,2),'rows');

                    % build sparse matrix of connections, springs
                    % connecting diagonal neighbors are weaker than
                    % the horizontal and vertical springs
                    nhv=size(hv_springs,1);
                    springs=sparse(repmat((1:nhv)',1,2),hv_springs, ...
                        repmat([1 -1],nhv,1),nhv,nm);

                    % eliminate knowns
                    rhs=-springs(:,known_list)*A(known_list);

                    % and solve...
                    B=A;
                    B(nan_list(:,1))=springs(:,nan_list(:,1))\rhs;

                case 5
                    % Average of 8 nearest neighbors

                    % generate sparse array to average 8 nearest neighbors
                    % for each nan element, be careful around edges
                    fda=spalloc(n*m,n*m,size(nan_list,1)*9);

                    % -1,-1
                    L = find((nan_list(:,2) > 1) & (nan_list(:,3) > 1));
                    nl=length(L);
                    if nl>0
                        fda=fda+sparse(repmat(nan_list(L,1),1,2), ...
                            repmat(nan_list(L,1),1,2)+repmat([-n-1, 0],nl,1), ...
                            repmat([1 -1],nl,1),n*m,n*m);
                    end

                    % 0,-1
                    L = find(nan_list(:,3) > 1);
                    nl=length(L);
                    if nl>0
                        fda=fda+sparse(repmat(nan_list(L,1),1,2), ...
                            repmat(nan_list(L,1),1,2)+repmat([-n, 0],nl,1), ...
                            repmat([1 -1],nl,1),n*m,n*m);
                    end
                    % +1,-1
                    L = find((nan_list(:,2) < n) & (nan_list(:,3) > 1));
                    nl=length(L);
                    if nl>0
                        fda=fda+sparse(repmat(nan_list(L,1),1,2), ...
                            repmat(nan_list(L,1),1,2)+repmat([-n+1, 0],nl,1), ...
                            repmat([1 -1],nl,1),n*m,n*m);
                    end
                    % -1,0
                    L = find(nan_list(:,2) > 1);
                    nl=length(L);
                    if nl>0
                        fda=fda+sparse(repmat(nan_list(L,1),1,2), ...
                            repmat(nan_list(L,1),1,2)+repmat([-1, 0],nl,1), ...
                            repmat([1 -1],nl,1),n*m,n*m);
                    end
                    % +1,0
                    L = find(nan_list(:,2) < n);
                    nl=length(L);
                    if nl>0
                        fda=fda+sparse(repmat(nan_list(L,1),1,2), ...
                            repmat(nan_list(L,1),1,2)+repmat([1, 0],nl,1), ...
                            repmat([1 -1],nl,1),n*m,n*m);
                    end
                    % -1,+1
                    L = find((nan_list(:,2) > 1) & (nan_list(:,3) < m));
                    nl=length(L);
                    if nl>0
                        fda=fda+sparse(repmat(nan_list(L,1),1,2), ...
                            repmat(nan_list(L,1),1,2)+repmat([n-1, 0],nl,1), ...
                            repmat([1 -1],nl,1),n*m,n*m);
                    end

                    % 0,+1
                    L = find(nan_list(:,3) < m);
                    nl=length(L);
                    if nl>0
                        fda=fda+sparse(repmat(nan_list(L,1),1,2), ...
                            repmat(nan_list(L,1),1,2)+repmat([n, 0],nl,1), ...
                            repmat([1 -1],nl,1),n*m,n*m);
                    end
                    % +1,+1
                    L = find((nan_list(:,2) < n) & (nan_list(:,3) < m));
                    nl=length(L);
                    if nl>0
                        fda=fda+sparse(repmat(nan_list(L,1),1,2), ...
                            repmat(nan_list(L,1),1,2)+repmat([n+1, 0],nl,1), ...
                            repmat([1 -1],nl,1),n*m,n*m);
                    end

                    % eliminate knowns
                    rhs=-fda(:,known_list)*A(known_list);

                    % and solve...
                    B=A;
                    k=nan_list(:,1);
                    B(k)=fda(k,k)\rhs(k);

            end
            % all done, make sure that B is the same shape as
            % A was when we came in.
            B=reshape(B,n,m);
            % ====================================================
            %      end of main function
            % ====================================================
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data)
            app.handlescattervisualoptioninspectionUIFigure.Units="normalized";
            app.handlescattervisualoptioninspectionUIFigure.Position(2)=0.1;
            app.handlescattervisualoptioninspectionUIFigure.Position(4)=0.8;
            movegui(app.handlescattervisualoptioninspectionUIFigure,'center')
            app.sampleselection.Items=data.filelist;
            app.data=data;
            sampleselectionValueChanged(app)
        end

        % Button pushed function: testsettings
        function testsettingsPushed(app, event)

            opt=handlescatterOptions;
            opt.ray1           = [app.ry1b.Value app.ry1a.Value];
            opt.ram1           = [app.rm1b.Value app.rm1a.Value];
            opt.ray2           = [app.ry2b.Value app.ry2a.Value];
            opt.ram2           = [app.rm2b.Value app.rm2a.Value];
            opt.cutout         = [app.cut_ry1.Value app.cut_rm1.Value app.cut_ry2.Value app.cut_rm2.Value];
            opt.interpolate    = [app.int_ry1.Value app.int_rm1.Value app.int_ry2.Value app.int_rm2.Value];
            opt.d2zero         = app.d2zero.Value;
            opt.negativetozero = app.n2z.Value;
            opt.samples        = 1:app.data.nSample;
            opt.iopt           = 'normal';
            opt.plot           = false;
            if matches(app.InterpolationSwitch.Value,'3D')
                opt.imethod    = 'inpaint';
            else
                opt.imethod    = 'fillmissing';
            end
            app.opt            = opt;
            dat=app.data;
            if isfield(app.data,'Smooth')
                dat=rmfield(dat,'Smooth');
            end
            treated=drEEMtoolbox.handlescatter(dat,app.opt);

            value = app.sampleselection.Value;
            sampleidx = matches(app.data.filelist,value);

            mat=squeeze(treated.X(sampleidx,:,:));

            title(app.eem,app.sampleselection.Value)
            sc = surfc(app.eem,app.data.Ex,app.data.Em,mat,...
                'EdgeColor','none');
            view(app.eem,0,90)
            app.eem.ZLim(2) = max(mat(~isnan(mat)));

            sc(2).ZLocation = 'zmax';
            sc(2).LineColor='k';
            sc(2).LevelList=linspace(min(mat(~isnan(mat))),max(mat(~isnan(mat))),10);
            set(app.eem,'ColorScale',app.plotscaleSwitch.Value)
            colormap(app.eem,app.colormapDropDown.Value)
            types={'ray1','ram1','ray2','ram2'};
            typen={'firstray','firstram','secray','secram'};
            datacut.X(1,:,:)=mat;
            datacut.Ex=app.data.Ex;
            datacut.Em=app.data.Em;
            datacut.nEx=app.data.nEx;
            datacut.nEm=app.data.nEm;
            datacut.nSample=1;
            datacut.i=1;
            for n=1:numel(types)
                temp=squeeze(app.synchronizeeem(datacut,types{n},'apply'));
                mat=temp.X;
                plot(app.(typen{n}),temp.Em,...
                    squeeze(mat),'Color',[0 0 0 0.6])
                xlim(app.(typen{n}),[-40 40])
                xline(app.(typen{n}),0,'Color','r','LineStyle','--')
                set(app.(typen{n}),'YScale',app.plotscaleSwitch.Value)
                grid(app.(typen{n}),'on')
            end
            value = app.contoursEditField.Value;
            h = get(app.eem,'Children');
            set(h(1),'LevelList',linspace(min(h(1).ZData(:),[],'omitnan'),max(h(1).ZData(:),[],'omitnan'),value))

        end

        % Value changed function: sampleselection
        function sampleselectionValueChanged(app, event)
            value = app.sampleselection.Value;
            sampleidx = matches(app.data.filelist,value);
            mat=squeeze(app.data.X(sampleidx,:,:));
            title(app.eem,app.sampleselection.Value);
            sc = surfc(app.eem,app.data.Ex,app.data.Em,mat,...
                'EdgeColor','none');
            view(app.eem,0,90)
            app.eem.ZLim(2) = max(mat(~isnan(mat)));

            sc(2).ZLocation = 'zmax';
            sc(2).LineColor='k';
            sc(2).LevelList=linspace(min(mat(~isnan(mat))),max(mat(~isnan(mat))),10);
            set(app.eem,'ColorScale',app.plotscaleSwitch.Value)
            colormap(app.eem,app.colormapDropDown.Value)
            types={'ray1','ram1','ray2','ram2'};
            typen={'firstray','firstram','secray','secram'};
            for n=1:numel(types)
                temp=squeeze(app.synchronizeeem(drEEMtoolbox.subdataset(app.data,not(sampleidx),[],[]),types{n},'apply'));
                mat=temp.X;
                plot(app.(typen{n}),temp.Em,...
                    squeeze(mat),'Color',[0 0 0 0.6])
                xlim(app.(typen{n}),[-40 40])
                xline(app.(typen{n}),0,'Color','r','LineStyle','--')
                set(app.(typen{n}),'YScale',app.plotscaleSwitch.Value)
                grid(app.(typen{n}),'on')
            end
            value = app.contoursEditField.Value;
            h = get(app.eem,'Children');
            set(h(1),'LevelList',linspace(min(h(1).ZData(:),[],'omitnan'),max(h(1).ZData(:),[],'omitnan'),value))

        end

        % Button pushed function: exeplot_2
        function exeplot_2ButtonPushed(app, event)
            opt=handlescatterOptions;
            opt.ray1           = [app.ry1b.Value app.ry1a.Value];
            opt.ram1           = [app.rm1b.Value app.rm1a.Value];
            opt.ray2           = [app.ry2b.Value app.ry2a.Value];
            opt.ram2           = [app.rm2b.Value app.rm2a.Value];
            opt.cutout         = [app.cut_ry1.Value app.cut_rm1.Value app.cut_ry2.Value app.cut_rm2.Value];
            opt.interpolate    = [app.int_ry1.Value app.int_rm1.Value app.int_ry2.Value app.int_rm2.Value];
            opt.d2zero         = app.d2zero.Value;
            opt.negativetozero = app.n2z.Value;
            opt.samples        = 'all';
            opt.iopt           = 'normal';
            opt.plot           = false;
            if matches(app.InterpolationSwitch.Value,'3D')
                opt.imethod    = 'inpaint';
            else
                opt.imethod    = 'fillmissing';
            end
            assignin('base','opt',opt)
            disp("Scatter treatment settings exported (look for 'opt')")
        end

        % Button pushed function: exeplot_3
        function exeplot_3ButtonPushed(app, event)
            try
                opt=struct;
                opt.ray1           = [app.ry1b.Value app.ry1a.Value];
                opt.ram1           = [app.rm1b.Value app.rm1a.Value];
                opt.ray2           = [app.ry2b.Value app.ry2a.Value];
                opt.ram2           = [app.rm2b.Value app.rm2a.Value];
                opt.cutout         = [app.cut_ry1.Value app.cut_rm1.Value app.cut_ry2.Value app.cut_rm2.Value];
                opt.interpolate    = [app.int_ry1.Value app.int_rm1.Value app.int_ry2.Value app.int_rm2.Value];
                opt.d2zero         = app.d2zero.Value;
                opt.negativetozero = app.n2z.Value;
                opt.samples        = 'all';
                opt.iopt           = 'normal';
                opt.plot           = false;
                if matches(app.InterpolationSwitch.Value,'3D')
                    opt.imethod    = 'inpaint';
                else
                    opt.imethod    = 'fillmissing';
                end
                dataout = drEEMtoolbox.handlescatter(app.data,opt);
                assignin('base','dataout',dataout)
                disp("Scatter treatment applied (look for 'dataout')")
            catch
                beep
                disp('Have you tested these settings yet?')
            end

        end

        % Value changed function: plotscaleSwitch
        function plotscaleSwitchValueChanged(app, event)
            value = app.plotscaleSwitch.Value;
            set(app.eem,"ColorScale",value)
            set(app.secram,"YScale",value)
            set(app.secray,"YScale",value)
            set(app.firstram,"YScale",value)
            set(app.firstray,"YScale",value)
        end

        % Value changed function: colormapDropDown
        function colormapDropDownValueChanged(app, event)
            value = app.colormapDropDown.Value;
            colormap(app.eem,value)
        end

        % Value changed function: contoursEditField
        function contoursEditFieldValueChanged(app, event)
            value = app.contoursEditField.Value;
            h = get(app.eem,'Children');
            set(h(1),'LevelList',linspace(min(h(1).ZData(:),[],'omitnan'),max(h(1).ZData(:),[],'omitnan'),value))
        end

        % Value changed function: cut_rm1, cut_rm2, cut_ry1, cut_ry2
        function cut_checkmarkChanged(app, event)
            if app.cut_ry1.Value
                app.int_ry1.Enable="on";
            else
                app.int_ry1.Enable="off";
                app.int_ry1.Value=false;
            end

            if app.cut_rm1.Value
                app.int_rm1.Enable="on";
            else
                app.int_rm1.Enable="off";
                app.int_rm1.Value=false;
            end

            if app.cut_ry2.Value
                app.int_ry2.Enable="on";
            else
                app.int_ry2.Enable="off";
                app.int_ry2.Value=false;
            end

            if app.cut_rm2.Value
                app.int_rm2.Enable="on";
            else
                app.int_rm2.Enable="off";
                app.int_rm2.Value=false;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.handlescattervisualoptioninspectionUIFigure = uifigure('Visible', 'off');
            colormap(app.handlescattervisualoptioninspectionUIFigure, 'parula');
            app.handlescattervisualoptioninspectionUIFigure.Name = 'handlescatter: visual option inspection';
            app.GridLayout4 = uigridlayout(app.handlescattervisualoptioninspectionUIFigure);
            app.samplenavitationPanel = uipanel(app.GridLayout4);
            app.samplenavitationPanel.Tooltip = {'Panel for sample selection, options export, and application of current settings to the dataset.'};
            app.samplenavitationPanel.Title = ':::: sample navitation';
            app.GridLayout5 = uigridlayout(app.samplenavitationPanel);
            app.sampleselection = uidropdown(app.GridLayout5);
            app.sampleselection.Items = {'Option 1', 'Option 2'};
            app.sampleselection.ValueChangedFcn = createCallbackFcn(app, @sampleselectionValueChanged, true);
            app.sampleselection.Tooltip = {'Select a sample. Upon selection, raw data is plotted. Hit "test settings" to apply parameters.'};
            app.SampleDropDownLabel = uilabel(app.GridLayout5);
            app.SampleDropDownLabel.Text = 'Sample';
            app.testsettings = uibutton(app.GridLayout5, 'push');
            app.testsettings.ButtonPushedFcn = createCallbackFcn(app, @testsettingsPushed, true);
            app.testsettings.Tooltip = {'Applies the current settings to the specified sample.'};
            app.testsettings.Text = 'test settings';
            app.exeplot_2 = uibutton(app.GridLayout5, 'push');
            app.exeplot_2.ButtonPushedFcn = createCallbackFcn(app, @exeplot_2ButtonPushed, true);
            app.exeplot_2.Tooltip = {'Creates variable "opt" in the workspace. Use with "handlescatter".'};
            app.exeplot_2.Text = 'export options';
            app.exeplot_3 = uibutton(app.GridLayout5, 'push');
            app.exeplot_3.ButtonPushedFcn = createCallbackFcn(app, @exeplot_3ButtonPushed, true);
            app.exeplot_3.Enable = 'off';
            app.exeplot_3.Visible = 'off';
            app.exeplot_3.Tooltip = {'Applies settings to all samples'};
            app.exeplot_3.Text = 'apply to all';
            app.excitationemissionmatrixPanel = uipanel(app.GridLayout4);
            app.excitationemissionmatrixPanel.Title = ':::: excitation-emission matrix';
            app.GridLayout3 = uigridlayout(app.excitationemissionmatrixPanel);
            app.eem = uiaxes(app.GridLayout3);
            title(app.eem, 'EEM')
            xlabel(app.eem, 'Excitation (nm)')
            ylabel(app.eem, 'Emission (nm)')
            zlabel(app.eem, 'Fluorescence')
            colormap(app.eem, 'parula')
            app.firstorderscatterplotsPanel = uipanel(app.GridLayout4);
            app.firstorderscatterplotsPanel.Tooltip = {'2D view of first order scatter, aligned at x-axis values of 0.'};
            app.firstorderscatterplotsPanel.Title = ':::: first order scatter plots';
            app.GridLayout = uigridlayout(app.firstorderscatterplotsPanel);
            app.firstray = uiaxes(app.GridLayout);
            title(app.firstray, 'Rayleigh')
            xlabel(app.firstray, 'nm from center')
            ylabel(app.firstray, 'Signal')
            zlabel(app.firstray, 'Z')
            colormap(app.firstray, 'parula')
            app.firstram = uiaxes(app.GridLayout);
            title(app.firstram, 'Raman')
            xlabel(app.firstram, 'nm from center')
            ylabel(app.firstram, 'Signal')
            zlabel(app.firstram, 'Z')
            colormap(app.firstram, 'parula')
            app.miscellaneousoptionsPanel = uipanel(app.GridLayout4);
            app.miscellaneousoptionsPanel.Tooltip = {'Other options'};
            app.miscellaneousoptionsPanel.Title = ' :::: miscellaneous options';
            app.GridLayout8 = uigridlayout(app.miscellaneousoptionsPanel);
            app.n2z = uicheckbox(app.GridLayout8);
            app.n2z.Tooltip = {'If checked, all negative values will be zero''ed.'};
            app.n2z.Text = 'Negative to zero';
            app.n2z.Value = true;
            app.d2zero = uispinner(app.GridLayout8);
            app.d2zero.Tooltip = {'How many nm below the first order Rayleigh should all values be set to zero?'};
            app.d2zero.Value = 30;
            app.nmtozeroLabel = uilabel(app.GridLayout8);
            app.nmtozeroLabel.Text = 'nm to zero:';
            app.InterpolationSwitch = uiswitch(app.GridLayout8, 'rocker');
            app.InterpolationSwitch.Items = {'3D', '2D'};
            app.InterpolationSwitch.Tooltip = {'3D iterative or 2D interpolation.'};
            app.InterpolationSwitch.Value = '3D';
            app.InterpolationSwitchLabel = uilabel(app.GridLayout8);
            app.InterpolationSwitchLabel.Text = 'Interpolation';
            app.firstorderscatterPanel = uipanel(app.GridLayout4);
            app.firstorderscatterPanel.Tooltip = {'First order scatter types.'};
            app.firstorderscatterPanel.Title = ':::: first order scatter';
            app.GridLayout6 = uigridlayout(app.firstorderscatterPanel);
            app.cut_ry1 = uicheckbox(app.GridLayout6);
            app.cut_ry1.ValueChangedFcn = createCallbackFcn(app, @cut_checkmarkChanged, true);
            app.cut_ry1.Tooltip = {'Checkmark to enable cutting out Rayleigh 1st order scatter'};
            app.cut_ry1.Text = '';
            app.cut_ry1.Value = true;
            app.int_ry1 = uicheckbox(app.GridLayout6);
            app.int_ry1.Tooltip = {'Checkmark to enable interpolating over removed scatter'};
            app.cut_rm1 = uicheckbox(app.GridLayout6);
            app.cut_rm1.ValueChangedFcn = createCallbackFcn(app, @cut_checkmarkChanged, true);
            app.cut_rm1.Tooltip = {'Checkmark to enable cutting out Raman 1st order scatter'};
            app.cut_rm1.Text = '';
            app.int_rm1 = uicheckbox(app.GridLayout6);
            app.int_rm1.Tooltip = {'Checkmark to enable interpolating over removed scatter'};
            app.cutLabel = uilabel(app.GridLayout6);
            app.cutLabel.Tooltip = {'Should the type of scatter below be cut?'};
            app.cutLabel.Text = 'cut';
            app.interpLabel = uilabel(app.GridLayout6);
            app.interpLabel.Tooltip = {'If checked below, an interpolation will replace the cut values with a guess.'};
            app.interpLabel.Text = 'interp.';
            app.belowLabel = uilabel(app.GridLayout6);
            app.belowLabel.Tooltip = {'nm below (emission)'};
            app.belowLabel.Text = 'below';
            app.aboveLabel = uilabel(app.GridLayout6);
            app.aboveLabel.Tooltip = {'nm above(emission)'};
            app.aboveLabel.Text = 'above';
            app.ry1b = uispinner(app.GridLayout6);
            app.ry1b.Limits = [0 50];
            app.ry1b.RoundFractionalValues = 'on';
            app.ry1b.Tooltip = {'How many nanometers below the predicted position should the scatter be cut?'};
            app.ry1b.Value = 30;
            app.rm1b = uispinner(app.GridLayout6);
            app.rm1b.Limits = [0 50];
            app.rm1b.RoundFractionalValues = 'on';
            app.rm1b.Tooltip = {'How many nanometers below the predicted position should the scatter be cut?'};
            app.rm1b.Value = 5;
            app.ry1a = uispinner(app.GridLayout6);
            app.ry1a.Limits = [0 50];
            app.ry1a.RoundFractionalValues = 'on';
            app.ry1a.Tooltip = {'How many nanometers above the predicted position should the scatter be cut?'};
            app.ry1a.Value = 10;
            app.rm1a = uispinner(app.GridLayout6);
            app.rm1a.Limits = [0 50];
            app.rm1a.RoundFractionalValues = 'on';
            app.rm1a.Tooltip = {'How many nanometers above the predicted position should the scatter be cut?'};
            app.rm1a.Value = 5;
            app.narrowLabel = uilabel(app.GridLayout6);
            app.narrowLabel.Enable = 'off';
            app.narrowLabel.Visible = 'off';
            app.narrowLabel.Tooltip = {'narrow by nm/50nm  excitation'};
            app.narrowLabel.Text = 'narrow';
            app.ry1n = uispinner(app.GridLayout6);
            app.ry1n.Step = 0.2;
            app.ry1n.Limits = [0 5];
            app.ry1n.Enable = 'off';
            app.ry1n.Visible = 'off';
            app.rm1n = uispinner(app.GridLayout6);
            app.rm1n.Step = 0.2;
            app.rm1n.Limits = [0 5];
            app.rm1n.Enable = 'off';
            app.rm1n.Visible = 'off';
            app.widenLabel = uilabel(app.GridLayout6);
            app.widenLabel.Enable = 'off';
            app.widenLabel.Visible = 'off';
            app.widenLabel.Tooltip = {'narrow by nm/50nm  excitation'};
            app.widenLabel.Text = 'widen';
            app.ry1w = uispinner(app.GridLayout6);
            app.ry1w.Step = 0.2;
            app.ry1w.Limits = [0 5];
            app.ry1w.Enable = 'off';
            app.ry1w.Visible = 'off';
            app.rm1w = uispinner(app.GridLayout6);
            app.rm1w.Step = 0.2;
            app.rm1w.Limits = [0 5];
            app.rm1w.Enable = 'off';
            app.rm1w.Visible = 'off';
            app.RayleighLabel = uilabel(app.GridLayout6);
            app.RayleighLabel.Text = 'Rayleigh';
            app.RamanLabel = uilabel(app.GridLayout6);
            app.RamanLabel.Text = 'Raman';
            app.secondorderscatterPanel = uipanel(app.GridLayout4);
            app.secondorderscatterPanel.Tooltip = {'Second order scatter occuring at 2x the first order scatter (instrumental artefact)'};
            app.secondorderscatterPanel.Title = ':::: second order scatter';
            app.GridLayout7 = uigridlayout(app.secondorderscatterPanel);
            app.GridLayout7.Tooltip = {'Second order scatter options'};
            app.rm2a = uispinner(app.GridLayout7);
            app.rm2a.Limits = [0 50];
            app.rm2a.RoundFractionalValues = 'on';
            app.rm2a.Tooltip = {'How many nanometers above the predicted position should the scatter be cut?'};
            app.rm2a.Value = 5;
            app.ry2a = uispinner(app.GridLayout7);
            app.ry2a.Limits = [0 50];
            app.ry2a.RoundFractionalValues = 'on';
            app.ry2a.Tooltip = {'How many nanometers above the predicted position should the scatter be cut?'};
            app.ry2a.Value = 10;
            app.rm2b = uispinner(app.GridLayout7);
            app.rm2b.Limits = [0 50];
            app.rm2b.RoundFractionalValues = 'on';
            app.rm2b.Tooltip = {'How many nanometers below the predicted position should the scatter be cut?'};
            app.rm2b.Value = 5;
            app.ry2b = uispinner(app.GridLayout7);
            app.ry2b.Limits = [0 50];
            app.ry2b.RoundFractionalValues = 'on';
            app.ry2b.Tooltip = {'How many nanometers below the predicted position should the scatter be cut?'};
            app.ry2b.Value = 10;
            app.aboveLabel_2 = uilabel(app.GridLayout7);
            app.aboveLabel_2.Tooltip = {'nm above (emission)'};
            app.aboveLabel_2.Text = 'above';
            app.belowLabel_2 = uilabel(app.GridLayout7);
            app.belowLabel_2.Tooltip = {'nm below (emission)'};
            app.belowLabel_2.Text = 'below';
            app.interpLabel_2 = uilabel(app.GridLayout7);
            app.interpLabel_2.Tooltip = {'If checked below, an interpolation will replace the cut values with a guess.'};
            app.interpLabel_2.Text = 'interp.';
            app.cutLabel_2 = uilabel(app.GridLayout7);
            app.cutLabel_2.Tooltip = {'Should the type of scatter below be cut?'};
            app.cutLabel_2.Text = 'cut';
            app.int_rm2 = uicheckbox(app.GridLayout7);
            app.int_rm2.Tooltip = {'Checkmark to enable interpolating over removed scatter'};
            app.cut_rm2 = uicheckbox(app.GridLayout7);
            app.cut_rm2.ValueChangedFcn = createCallbackFcn(app, @cut_checkmarkChanged, true);
            app.cut_rm2.Tooltip = {'Checkmark to enable cutting out Raman 2nd order scatter'};
            app.cut_rm2.Text = '';
            app.int_ry2 = uicheckbox(app.GridLayout7);
            app.int_ry2.Tooltip = {'Checkmark to enable interpolating over removed scatter'};
            app.cut_ry2 = uicheckbox(app.GridLayout7);
            app.cut_ry2.ValueChangedFcn = createCallbackFcn(app, @cut_checkmarkChanged, true);
            app.cut_ry2.Tooltip = {'Checkmark to enable cutting out Rayleigh 2nd order scatter'};
            app.cut_ry2.Text = '';
            app.narrowLabel_2 = uilabel(app.GridLayout7);
            app.narrowLabel_2.Enable = 'off';
            app.narrowLabel_2.Visible = 'off';
            app.narrowLabel_2.Tooltip = {'narrow by nm/50nm  excitation'};
            app.narrowLabel_2.Text = 'narrow';
            app.ry2n = uispinner(app.GridLayout7);
            app.ry2n.Step = 0.2;
            app.ry2n.Limits = [0 5];
            app.ry2n.Enable = 'off';
            app.ry2n.Visible = 'off';
            app.rm2n = uispinner(app.GridLayout7);
            app.rm2n.Step = 0.2;
            app.rm2n.Limits = [0 5];
            app.rm2n.Enable = 'off';
            app.rm2n.Visible = 'off';
            app.widenLabel_2 = uilabel(app.GridLayout7);
            app.widenLabel_2.Enable = 'off';
            app.widenLabel_2.Visible = 'off';
            app.widenLabel_2.Tooltip = {'narrow by nm/50nm  excitation'};
            app.widenLabel_2.Text = 'widen';
            app.ry2w = uispinner(app.GridLayout7);
            app.ry2w.Step = 0.2;
            app.ry2w.Limits = [0 5];
            app.ry2w.Enable = 'off';
            app.ry2w.Visible = 'off';
            app.rm2w = uispinner(app.GridLayout7);
            app.rm2w.Step = 0.2;
            app.rm2w.Limits = [0 5];
            app.rm2w.Enable = 'off';
            app.rm2w.Visible = 'off';
            app.RayleighLabel_2 = uilabel(app.GridLayout7);
            app.RayleighLabel_2.Text = 'Rayleigh';
            app.RamanLabel_2 = uilabel(app.GridLayout7);
            app.RamanLabel_2.Text = 'Raman';
            app.secondorderscatterplotsPanel = uipanel(app.GridLayout4);
            app.secondorderscatterplotsPanel.Tooltip = {'2D view of second order scatter, aligned at x-axis values of 0.'};
            app.secondorderscatterplotsPanel.Title = ':::: second order scatter plots';
            app.GridLayout2 = uigridlayout(app.secondorderscatterplotsPanel);
            app.secray = uiaxes(app.GridLayout2);
            title(app.secray, 'Rayleigh')
            xlabel(app.secray, 'nm from center')
            ylabel(app.secray, 'Signal')
            zlabel(app.secray, 'Z')
            colormap(app.secray, 'parula')
            app.secram = uiaxes(app.GridLayout2);
            title(app.secram, 'Raman')
            xlabel(app.secram, 'nm from center')
            ylabel(app.secram, 'Signal')
            zlabel(app.secram, 'Z')
            colormap(app.secram, 'parula')
            app.plotoptionsPanel = uipanel(app.GridLayout4);
            app.plotoptionsPanel.Tooltip = {'Options to control contour plot layout'};
            app.plotoptionsPanel.Title = ':::: plot options';
            app.plotscaleSwitchLabel = uilabel(app.plotoptionsPanel);
            app.plotscaleSwitchLabel.Text = 'plot scale';
            app.plotscaleSwitch = uiswitch(app.plotoptionsPanel, 'slider');
            app.plotscaleSwitch.Items = {'linear', 'log'};
            app.plotscaleSwitch.ValueChangedFcn = createCallbackFcn(app, @plotscaleSwitchValueChanged, true);
            app.plotscaleSwitch.Value = 'linear';
            app.colormapDropDownLabel = uilabel(app.plotoptionsPanel);
            app.colormapDropDownLabel.Text = 'colormap';
            app.colormapDropDown = uidropdown(app.plotoptionsPanel);
            app.colormapDropDown.Items = {'turbo', 'parula', 'jet', 'hsv'};
            app.colormapDropDown.ValueChangedFcn = createCallbackFcn(app, @colormapDropDownValueChanged, true);
            app.colormapDropDown.Value = 'turbo';
            app.contoursEditFieldLabel = uilabel(app.plotoptionsPanel);
            app.contoursEditFieldLabel.Text = 'contours:';
            app.contoursEditField = uieditfield(app.plotoptionsPanel, 'numeric');
            app.contoursEditField.ValueChangedFcn = createCallbackFcn(app, @contoursEditFieldValueChanged, true);
            app.contoursEditField.Value = 15;
            % Show the figure after all components are created
            app.handlescattervisualoptioninspectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = diagscatter(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.handlescattervisualoptioninspectionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.handlescattervisualoptioninspectionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.handlescattervisualoptioninspectionUIFigure)
        end
    end
end