classdef viewhistory < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        drEEMviewhistoryUIFigure      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        SavelistasspreadsheetButton   matlab.ui.control.Button
        TransferoptionstoworkspaceButton  matlab.ui.control.Button
        ViewselecteddatasetButton     matlab.ui.control.Button
        ViewselectedoptionsButton     matlab.ui.control.Button
        RestoreselecteddatasetButton  matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
    end

    
    properties (Access = private)
        data % Description
        history % Description
        selectedB % Description
        selectedD % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data)
            app.data = data;
            app.history = app.data.history;
            
            app.history =drEEMhistory.convert2table(app.history);
            %app.history=struct2table(app.history);
            %{'timestamp','fname','fmessage','details','backup','previous','usercomment'}
            app.UITable.Data=app.history(:,[1 2 3 7]);

            fs=unique(app.UITable.Data.fname);
            col=brighten(turbo(numel(fs)),.9);
            for j = 1 : numel(fs)
                s  = uistyle(BackgroundColor=col(j,:));
                idx=matches(app.UITable.Data.fname,fs{j});
                addStyle(app.UITable,s,'row',find(idx))
            end
            
                        % Work in progress for multiple entries
            % for j=1:height(app.UITable.Data)
            %     cmts=app.UITable.Data.usercomment{j};
            %     nCmts=numel(cmts);
            %     if nCmts>1
            %         t1=app.UITable.Data(1:j,:);
            %         t1.usercomment{j}=cmts(1);
            %         t2=app.UITable.Data(j+1,:);
            %         ti=t1;ti=ti(j,:);tt=ti;
            %         for k=2:nCmts
            %             tn=tt;
            %             tn.usercomment=cmts(k);
            %             tn.timestamp=NaT;
            %             tn.fname="";
            %             tn.fmessage="";
            %             tn.usercomment=cmts(k);
            %             ti=[ti;tn];
            %         end
            %         t=[t1;ti;t2];
            % 
            %         asd
            %     end
            % end
            %% Unsuccessful multiple rows in one history
            % comments=app.UITable.Data.usercomment;
            % 
            % for j=1:numel(comments)
            %     if numel(comments{j})>1
            %         new=['<html>'];
            %         for k=1:numel(comments{j})
            %             new=[new,char([char(comments{j}(k)),'<br />'])];
            %         end
            %         new=[new(1:end-6),'</html>'];
            %         comments{j}=new;
            %         style(j)=categorical(cellstr('html'));
            %     else
            %         style(j)=categorical(cellstr('none'));
            %     end
            % 
            % end
            % sh = uistyle("Interpreter","html");
            % sn = uistyle("Interpreter","none");
            % idx=style=='html';
            % addStyle(app.UITable,sh,'cell',[find(idx),4])
            % app.UITable.Data.usercomment=comments;

        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;
            dat=drEEMdataset.restore(app.data,selection);
            app.selectedB=dat;
            if iscell(app.selectedB)
                app.selectedB=app.selectedB{1};
            end
            app.selectedD=app.history.details{selection};

            if app.selectedB.nSample~=0;
                app.RestoreselecteddatasetButton.Enable="on";
                app.ViewselecteddatasetButton.Enable="on";
            else
                app.RestoreselecteddatasetButton.Enable="off";
                app.ViewselecteddatasetButton.Enable="off";
            end
            if matches(class(app.selectedD),{'struct','handlescatterOptions'})
                app.ViewselectedoptionsButton.Enable="on";
                app.TransferoptionstoworkspaceButton.Enable="on";
            else
                app.ViewselectedoptionsButton.Enable="off";
                app.TransferoptionstoworkspaceButton.Enable="off";
            end
        end

        % Button pushed function: RestoreselecteddatasetButton
        function RestoreselecteddatasetButtonPushed(app, event)
            assignin('base','restoredData',app.selectedB)
            disp('Backup restored (look for "restoredData"')
        end

        % Button pushed function: ViewselecteddatasetButton
        function ViewselecteddatasetButtonPushed(app, event)
            
            f=uifigure;
            f.Name='drEEM viewhistory -> viewing selected backup';
            ta=uitable(f);
            ta.Position=[0 0 f.Position(3) f.Position(4)];
            txt=formattedDisplayText(app.selectedB);
            txt=erase(txt,'  <a href="matlab:helpPopup drEEMdataset" style="font-weight:bold">drEEMdataset</a> with properties:↵↵');
            txt=splitlines(txt);
            txt=txt(3:end-1);
            txt=split(txt,':');
            t=table;
            t.field=txt(:,1);
            t.information=txt(:,2);
            ta.Data=t;
        end

        % Button pushed function: ViewselectedoptionsButton
        function ViewselectedoptionsButtonPushed(app, event)
            f=uifigure;
            f.Name='drEEM viewhistory -> viewing selected options of function call';
            ta=uitable(f);
            ta.Position=[0 0 f.Position(3) f.Position(4)];
            txt=formattedDisplayText(app.selectedD);
            txt=split(txt,'</a> with properties:');
            txt=splitlines(txt(end));
            txt(matches(txt,""))=[];
            %txt=txt(3:end-1);
            txt=split(txt,':');
            t=table;
            t.field=txt(:,1);
            t.information=txt(:,2);
            ta.Data=t;
        end

        % Button pushed function: TransferoptionstoworkspaceButton
        function TransferoptionstoworkspaceButtonPushed(app, event)
            assignin('base','restoredData',app.selectedD)
            disp('Options assigned to workspace variable (look for "restoredOptions"')
        end

        % Button pushed function: SavelistasspreadsheetButton
        function SavelistasspreadsheetButtonPushed(app, event)
            [file,path] = uiputfile('drEEM_dataset_history.ods','select location of file');
            figure(app.drEEMviewhistoryUIFigure)
            t=app.history(:,[1 2 6 3]);
            t.Properties.VariableNames={'timestamp','function','user comment','function message'};
            delete([path,file])
            writetable(t,[path,file],"FileType","spreadsheet");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.drEEMviewhistoryUIFigure = uifigure('Visible', 'off');
            app.drEEMviewhistoryUIFigure.Name = 'drEEM: viewhistory';
            app.GridLayout = uigridlayout(app.drEEMviewhistoryUIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Date / Time'; 'drEEM function'; 'details'; 'Comments by user'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Multiselect = 'off';
            app.RestoreselecteddatasetButton = uibutton(app.GridLayout, 'push');
            app.RestoreselecteddatasetButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreselecteddatasetButtonPushed, true);
            app.RestoreselecteddatasetButton.Enable = 'off';
            app.RestoreselecteddatasetButton.Text = 'Restore selected dataset';
            app.ViewselectedoptionsButton = uibutton(app.GridLayout, 'push');
            app.ViewselectedoptionsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewselectedoptionsButtonPushed, true);
            app.ViewselectedoptionsButton.Enable = 'off';
            app.ViewselectedoptionsButton.Text = 'View selected options';
            app.ViewselecteddatasetButton = uibutton(app.GridLayout, 'push');
            app.ViewselecteddatasetButton.ButtonPushedFcn = createCallbackFcn(app, @ViewselecteddatasetButtonPushed, true);
            app.ViewselecteddatasetButton.Enable = 'off';
            app.ViewselecteddatasetButton.Text = 'View selected dataset';
            app.TransferoptionstoworkspaceButton = uibutton(app.GridLayout, 'push');
            app.TransferoptionstoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @TransferoptionstoworkspaceButtonPushed, true);
            app.TransferoptionstoworkspaceButton.Enable = 'off';
            app.TransferoptionstoworkspaceButton.Text = 'Transfer options to workspace';
            app.SavelistasspreadsheetButton = uibutton(app.GridLayout, 'push');
            app.SavelistasspreadsheetButton.ButtonPushedFcn = createCallbackFcn(app, @SavelistasspreadsheetButtonPushed, true);
            app.SavelistasspreadsheetButton.Text = 'Save list as spreadsheet';
            % Show the figure after all components are created
            app.drEEMviewhistoryUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = viewhistory(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.drEEMviewhistoryUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.drEEMviewhistoryUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.drEEMviewhistoryUIFigure)
        end
    end
end