classdef vieweems < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        vieweemsUIFigure               matlab.ui.Figure
        GridLayout5                    matlab.ui.container.GridLayout
        plotcontrolPanel               matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        spectraButton                  matlab.ui.control.Button
        showabsCheckBox                matlab.ui.control.CheckBox
        holdspectraCheckBox            matlab.ui.control.CheckBox
        keepselectionCheckBox          matlab.ui.control.CheckBox
        EEMformatPanel                 matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        KeepCLimsCheckBox              matlab.ui.control.CheckBox
        focuspercentile10CheckBox      matlab.ui.control.CheckBox
        percentilelimitSlider          matlab.ui.control.Slider
        percentilelimitSliderLabel     matlab.ui.control.Label
        showpeaksCheckBox              matlab.ui.control.CheckBox
        contoursCheckBox               matlab.ui.control.CheckBox
        coloraxisscaleSwitch           matlab.ui.control.Switch
        KeeprotationCheckBox           matlab.ui.control.CheckBox
        drop_colormap                  matlab.ui.control.DropDown
        colormapLabel                  matlab.ui.control.Label
        contoursEditFieldLabel         matlab.ui.control.Label
        contoursEditField              matlab.ui.control.NumericEditField
        excitationemissionmatrixPanel  matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        upperLabel                     matlab.ui.control.Label
        lowerLabel                     matlab.ui.control.Label
        clim_lower                     matlab.ui.control.Slider
        clim_upper                     matlab.ui.control.Slider
        eem                            matlab.ui.control.UIAxes
        spectraPanel                   matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        em                             matlab.ui.control.UIAxes
        ex                             matlab.ui.control.UIAxes
        samplenaviationPanel           matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        Drop_sample                    matlab.ui.control.DropDown
        nextButton                     matlab.ui.control.Button
        previousButton                 matlab.ui.control.Button
        drop_model                     matlab.ui.control.DropDown
        DropDown_type                  matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        data=struct;
        opts=struct;
    end
    
    methods (Access = private)
        
        function f=findcomponents(app,model)
            cnt=1;
            f=zeros(1,100);
            for n=1:100
                if isfield(model,['Model',num2str(n)])
                    % Field is there but might be 'No valid model found.'
                    if not(ischar(model.(['Model',num2str(n)]){1}))
                        f(cnt)=n;
                    end
                end
                cnt=cnt+1;
            end
            f(f==0)=[];
        end
        
        function cols = colmaker(app,i)
            collist= [0 0 0;...
                0.368627450980392,0.309803921568627,0.635294117647059;...
                0.197386603025884,0.512851710442123,0.740257214831838;...
                0.353920722469962,0.729472517932409,0.656177840632872;...
                0.597784406620190,0.840777034550949,0.644490274269565;...
                0.841701969675001,0.940885130292891,0.609611616709332;...
                0.931851609124017,0.957193786605551,0.661676168589149;...
                0.976267475434952,0.928364732010819,0.658313136694680;...
                0.995493878994122,0.822714483114813,0.482765825462557;...
                0.987654277338283,0.615414278604657,0.339114037801447;...
                0.943815034723900,0.390980258870390,0.266846392952373;...
                0.820562112287474,0.223926402795306,0.309367864350849;...
                0.619607843137255,0.00392156862745098,0.258823529411765];
            cols=collist(1:i,:);
        end

        function eemcontourf(app)

            x=app.data.Ex;
            y=app.data.Em;

            if contains(app.DropDown_type.Value,'resi')
                fhere=app.opts.f(matches(app.drop_model.Items,app.drop_model.Value));
                Xname=['X_Model',num2str(fhere),'resi'];
                try
                    mat=squeeze(app.data.userdata.(Xname)(app.opts.sample,:,:));
                catch
                    error('Could not retreive residuals for plotting. Run "checkdataset".')
                end
            elseif contains(app.DropDown_type.Value,'model')
                fhere=app.opts.f(matches(app.drop_model.Items,app.drop_model.Value));
                Xname=['X_Model',num2str(fhere)];
                try
                    mat=squeeze(app.data.userdata.(Xname)(app.opts.sample,:,:));
                catch
                    error('Could not retreive model for plotting. Run "checkdataset".')
                end
            else
                mat=squeeze(app.data.('X')(app.opts.sample,:,:));
            end

            
            if numel(unique(mat))==1
                cla(app.eem)
                plot(app.eem,NaN,NaN,'DisplayName',num2str(1));
                text(app.eem,290,400,'Cannot plot constant data.')
                xlim(app.eem,[250 350])
                ylim(app.eem,[350 450])
                return
            end
            hold(app.eem,'off')
            [az,el] = view(app.eem);
            switch app.DropDown_type.Value
                case {'data','model'}
                    sc = surfc(app.eem,x,y,mat,'EdgeColor','none','FaceColor','flat');
                   
                    if not(app.KeeprotationCheckBox.Value)
                       view(app.eem,0,90)
                    else
                        view(app.eem,az,el)
                    end
                     
                    if isempty(max(mat(~isnan(mat))))
                        return
                    else
                        app.eem.ZLim(2) = max(mat(~isnan(mat)));
                    end
                    sc(2).ZLocation = 'zmax';
                    sc(2).LineColor='k';
                    sc(2).LineWidth = 0.5;
                    sc(2).LevelList=linspace(min(mat(~isnan(mat))),max(mat(~isnan(mat))),app.contoursEditField.Value);
                    app.drop_colormapValueChanged;
                    zlim(app.eem,[min(mat(~isnan(mat))),max(mat(~isnan(mat)))])
                    if not(app.KeepCLimsCheckBox.Value)
                        CLim=[min(mat(~isnan(mat))) max(mat(~isnan(mat)))];
                    else
                        CLim=[prctile(mat(:),app.clim_lower.Value) prctile(mat(:),app.clim_upper.Value)];
                    end
                    caxis(app.eem,CLim)
                    app.drop_colormap;
                case 'residuals'
                    sc = surfc(app.eem,x,y,mat,'EdgeColor','none','FaceColor','flat');
                    if not(app.KeeprotationCheckBox.Value)
                       view(app.eem,0,90)
                    else
                        view(app.eem,az,el)
                    end
                    app.eem.ZLim(2) = max(mat(~isnan(mat)));
                    sc(2).ZLocation = 'zmax';
                    sc(2).LineColor='k';
                    sc(2).LevelList=linspace(min(mat(~isnan(mat))),max(mat(~isnan(mat))),app.contoursEditField.Value);
                    if not(app.KeepCLimsCheckBox.Value)
                        CLim=[min(mat(~isnan(mat))) max(mat(~isnan(mat)))];
                    else
                        CLim=[prctile(mat(:),app.clim_lower.Value) prctile(mat(:),app.clim_upper.Value)];
                    end
                    caxis(app.eem,CLim)
                    zlim(app.eem,CLim)
                    levels=linspace(CLim(1),CLim(2),100);
                    n_neg=sum(levels<0);
                    n_pos=sum(levels>0);
                    app.drop_colormap.Enable='off';
                    colormap(app.eem,residualcolormap(app,n_neg,n_pos))
            end
            app.eem.Title.String=app.data.filelist(app.opts.sample);
            

            % Make sure to check if contours should be plotted
            app.contoursCheckBoxValueChanged


            app.eem.UserData = mat(:);
            if app.showpeaksCheckBox.Value
                app.showpeaksCheckBoxValueChanged;
            end


        end
        
        function specview(app)

            if isempty(app.opts.spectra)
                return
            end


            if not(isempty(app.opts.f))
                fhere=app.opts.f(matches(app.drop_model.Value,app.drop_model.Items));
            end
            if contains(app.opts.Xtype,'resi')
                Xname=['X_Model',num2str(fhere),'resi'];
                try
                    eem=squeeze(data.userdata.(Xname)(app.opts.sample,:,:));
                catch
                    error('Could not retreive residuals for plotting. Run "checkdataset".')
                end
            elseif contains(app.opts.Xtype,'model')
                Xname=['X_Model',num2str(fhere)];
                try
                    eem=squeeze(data.userdata.(Xname)(app.opts.sample,:,:));
                catch
                    error('Could not retreive model for plotting. Run "checkdataset".')
                end
            else
                eem=squeeze(app.data.('X')(app.opts.sample,:,:));
            end


            hc=get(app.ex,'Children');
            if app.holdspectraCheckBox.Value
                for n=1:numel(hc)
                    if ~isempty(strfind(hc(n).DisplayName,'Raman'))||~isempty(strfind(hc(n).DisplayName,'Rayleigh'))
                        %contains(hc(n).DisplayName,{'Raman','Rayleigh'}) % If only everyone would use the latest software
                        delete(hc(n))
                    end
                end
            end

            if app.holdspectraCheckBox.Value
                hold(app.ex,'on')
                hold(app.em,'on')
            else
                hold(app.ex,'off')
                hold(app.em,'off')
            end


            yyaxis(app.ex,'left')
            set(app.ex,'YColor','k')
            yyaxis(app.ex,'right')
            set(app.ex,'YColor',[1 .4 .4])
            yyaxis(app.ex,'left')
            plot(app.ex,app.data.Ex,squeeze(eem(app.data.Em==app.opts.spectra(1),:)),'-k','DisplayName',num2str(round(app.opts.spectra(1))))
            app.ex.YLimMode='auto';
            app.ex.YLimitMethod='padded';
            cylim=get(app.ex,'YLim');
            cxlim=get(app.ex,'XLim');
             y = [0 cylim(2)];
            

            wl = app.opts.spectra(1);
            ray1st=[wl wl];
            ray2nd=ray1st/2;

            ram1=(1*10^7*((1*10^7)/(wl)+3382)^-1);
            ram2=(1*10^7*((1*10^7)/(wl/2)+3382)^-1);

            ram1st=[ram1 ram1];
            ram2nd=[ram2 ram2];
            if not(app.opts.holdSwitch)
                line(app.ex,ram1st,y,...
                    'DisplayName','Raman','Color','k','LineWidth',0.5);
                line(app.ex,ram2nd,y,...
                    'DisplayName','Raman','Color','k','LineWidth',0.5);
                line(app.ex,ray1st,y,...
                    'DisplayName','Rayleigh','Color','r','LineWidth',0.5);
                line(app.ex,ray2nd,y,...
                    'DisplayName','Rayleigh','Color','r','LineWidth',0.5);
                set(app.ex,'XLim',cxlim);
                set(app.ex,'YLim',cylim);
            end
            
            h=findobj(app.ex,'Type', 'line');
            h=h(not(cellfun(@(x) contains(x,{'Ra','abs'}),(arrayfun(@(x) x.DisplayName,h,'uni',false)))));
            if numel(h)>1
                col=colmaker(app,numel(h));
                set(h, {'color'}, num2cell(col,2));
                legend1 = legend(app.ex,h,'location','best');
                try %#ok<TRYNC>
                    legend1.ItemTokenSize=[10 6];
                end
            end

            set(app.ex,'Tag','ex')
            title(app.ex,['Ex. spectrum at Em = ',num2str(app.opts.spectra(1))])


            hc=get(app.em,'Children');
            if app.holdspectraCheckBox.Value
                for n=1:numel(hc)
                    if ~isempty(strfind(hc(n).DisplayName,'Raman'))||~isempty(strfind(hc(n).DisplayName,'Rayleigh'))
                        %contains(hc(n).DisplayName,{'Raman','Rayleigh'}) % If only everyone would use the latest software
                        delete(hc(n))
                    end
                end
            end

            plot(app.em,app.data.Em,squeeze(eem(:,app.data.Ex==app.opts.spectra(2))),'-k','DisplayName',num2str(round(app.opts.spectra(2))))


            app.em.YLimMode='auto';
            app.em.YLimitMethod='padded';
            cylim=get(app.em,'YLim');
            cxlim=get(app.em,'XLim');
            y = [0 cylim(2)];
            
            
            app.ex.YLimMode='auto';
            app.ex.YLimitMethod='padded';app.ex.YLimMode='auto';
            app.ex.YLimitMethod='padded';app.ex.YLimMode='auto';
            app.ex.YLimitMethod='padded';
            wl = app.opts.spectra(2);

            ram1=1*10^7*((1*10^7)/(wl)-3382)^-1;
            ram2=(1*10^7*((1*10^7)/(wl)-3382)^-1)*2;

            ram1st=[ram1 ram1];
            ram2nd=[ram2 ram2];
            ray1st=[wl wl];
            ray2nd=[wl*2 wl*2];
            if not(app.opts.holdSwitch)
                line(app.em,ram1st,y,...
                    'DisplayName','Raman','Color','k','LineWidth',0.5);
                line(app.em,ram2nd,y,...
                    'DisplayName','Raman','Color','k','LineWidth',0.5);
                line(app.em,ray1st,y,...
                    'DisplayName','Rayleigh 1st','Color','r','LineWidth',0.5);
                line(app.em,ray2nd,y,...
                    'DisplayName','Rayleigh','Color','r','LineWidth',0.5);
                set(app.em,'XLim',cxlim);
                set(app.em,'YLim',cylim);

            end
            h=findobj(app.em,'Type', 'line');
            h=h(not(cellfun(@(x) contains(x,'Ra'),(arrayfun(@(x) x.DisplayName,h,'uni',false)))));
            if numel(h)>1
                col=colmaker(app,numel(h));
                set(h, {'color'}, num2cell(col,2));
                legend1 = legend(app.em,h,'location','best');
                try %#ok<TRYNC>
                    legend1.ItemTokenSize=[10 6];
                end
            end
            title(app.em,['Em. spectrum at Ex = ',num2str(app.opts.spectra(2))])
            set(app.em,'Tag','em')

        end


        function map = residualcolormap(app,nneg,npos)

            % ncol=ceil(round(ncol)/2);

            nmap=[linspace(0,1,nneg)' linspace(0.45,1,nneg)' linspace(0.737,1,nneg)'];
            pmap=flipud([flipud(linspace(1,0.686,npos)') flipud(linspace(1,0.208,npos)') flipud(linspace(1,0.278,npos)')]);

            map=[nmap;pmap];

        end

        
        function clearspectra(app)
            hc=get(app.ex,'Children');
            hc=[hc;get(app.em,'Children')];
            for n=1:numel(hc)
                delete(hc(n))
            end
            cla(app.ex)
            cla(app.em)
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, varargin)
            try
                app.vieweemsUIFigure.Visible='off';
                h = figure('units','normalized','position',[0.4 0.5 0.1 0.05],...
                    'windowstyle','modal',"Color",'w','Name','','NumberTitle','off');  
                uicontrol('style','text','string','eemreview initializing...','units','normalized','position',[0.01 0.001 1 0.5],"BackgroundColor",'w');
                
                app.vieweemsUIFigure.Units="normalized";
                app.vieweemsUIFigure.Position=[0.15 0.15 0.7 0.6];
                movegui(app.vieweemsUIFigure,'center')
                warning off
                params = inputParser;
                params.addParameter('eem', 'X', @ischar);
                params.addParameter('title', 'filelist', @ischar);
                params.addParameter('samples', [], @isnumeric);
                params.addParameter('nContours', 15, @isnumeric);
                params.addParameter('LineStyle', '-', @ischar);
                params.addParameter('peaks', false, @islogical);
                params.addParameter('hold', false, @islogical);
                params.addParameter('keepselection',false, @islogical);
                params.addParameter('showabsorbance',false,@islogical);
                params.parse(varargin{:});
                
                app.opts.Xname      = params.Results.eem;
                app.opts.ContLine   = params.Results.LineStyle;
                app.opts.titlefield = params.Results.title;
                app.opts.ncontours  = params.Results.nContours;
                app.opts.peakSwitch = params.Results.peaks;
                app.opts.holdSwitch = params.Results.hold;
                app.opts.keepSwitch = params.Results.keepselection;
                app.opts.showabs    = params.Results.showabsorbance;
                app.opts.sample     = 1;
                app.opts.spectra    = [];
                app.keepselectionCheckBox.Enable=false;
                
                app.data=data;
                app.previousButton.Enable='off';
                if app.opts.peakSwitch
                    app.opts.ContLine = 'none';
                end
    
                if app.opts.ncontours >= 50
                    app.opts.ContLine = 'none';
                end
    
                app.opts.offerabs=false;
                if isfield(app.data,'Abs_wave')&&isfield(app.data,'Abs')
                    if size(app.data.Abs_wave,1)==size(app.data.Abs,2)
                        if size(app.data.Abs,1)==app.data.nSample
                            app.opts.offerabs=true;
                        end
                    end
                end
                if not(app.opts.offerabs)
                    app.showabsCheckBox.Enable='off';
                end
                f=find(arrayfun(@(x) not(isempty(x.loads{1})),data.models))';
                app.opts.f=f;
                app.opts.sample=1;
                app.drop_model.Items=cellstr(strcat(num2str(f'),repmat(' - component model',numel(f),1)));
                app.drop_model.Enable='off';
                if isempty(f)
                    app.drop_model.Enable='off';
                    app.DropDown_type.Items={'data'};
                end
                
                
                for n=1:numel(f)
                    data.userdata.(['X_Model',num2str(f(n))])=nmodel(data.models(f(n)).loads);
                    data.userdata.(['X_Model',num2str(f(n)),'resi'])=data.X-data.userdata.(['X_Model',num2str(f(n))]);
                end
                if not(contains(app.opts.Xname,'resi'))&&not(contains(app.opts.Xname,'Model'))
                    app.opts.eemmode='data';
                    app.opts.Xtype='data';
                elseif contains(app.opts.Xname,'Model')&&not(contains(app.opts.Xname,'resi'))
                    app.opts.eemmode='data';
                    app.opts.Xtype='model';
                elseif contains(app.opts.Xname,'resi')
                    app.opts.eemmode='residuals';
    
                    app.opts.Xtype='residual';
                end
    
                
                try
                    filelist=data.(app.opts.titlefield);
                    if isnumeric(filelist)
                        filelist=cellstr(num2str(filelist));
                    end
                catch
                    warning('Could not parse the info for plot titles. Using simple indicies instead. ')
                    filelist=cellstr(num2str((1:data.nSample)'));
                end
    
                for n=1:numel(filelist)
                    filelist{n}=[num2str(n),' : ',filelist{n}];
                    try %#ok<TRYNC>
                        filelist{n}=[filelist{n},' (.i ',num2str(data.i(n)),')'];
                    end
                end
                data.filelist=filelist;
                app.Drop_sample.Items=data.filelist;
                pos=app.eem.Position;
                colorbar(app.eem);
                app.eem.Position = pos;
                app.data=data;
                app.eemcontourf;
                addToolbarExplorationButtons(app.eem)
                addToolbarExplorationButtons(app.ex)
                addToolbarExplorationButtons(app.em)

                close(h)
                app.vieweemsUIFigure.Visible='on';
            catch ME
                delete(app)
                close(h)
                rethrow(ME)
            end
        end

        % Value changed function: drop_colormap
        function drop_colormapValueChanged(app, event)
            value = app.drop_colormap.Value;
            switch value
                case 'parula'
                    set(app.eem ,'Colormap',parula)
                case 'turbo'
                    set(app.eem,'Colormap',turbo)
                case 'jet'
                    set(app.eem,'Colormap',jet)
                case 'hsv'
                    set(app.eem,'Colormap',hsv)
                case 'pos/neg'
                    CLims=app.eem.CLim;
                    CSpace=linspace(CLims(1),CLims(2),100);
                    
                    nneg=sum(CSpace<=0);
                    npos=sum(CSpace>0);
                    set(app.eem,'Colormap',residualcolormap(app,nneg,npos))
            end
        end

        % Button pushed function: spectraButton
        function spectraButtonPushed(app, event)
            
            method='drawpoint';
            switch method
                case 'datatip'
                    oldstate=app.contoursCheckBox.Value;
                    changed=false;
                    if oldstate
                        app.contoursCheckBox.Value=false;
                        app.contoursCheckBoxValueChanged;
                        changed=true;
                    end
                    tth = findall(app.eem,'Type','hggroup');     % find any pre-existing data tips
                    delete(tth)                                  % remove them
                    %datacursormode(app.vieweemsUIFigure)
                    tth = [];
                    cnt=1;
                    while isempty(tth)
                        %waitforbuttonpress
                         tth = findall(app.eem,'Type','hggroup');
                         pause(0.01) % important, without it Matlab crashes
                         cnt=cnt+1;
                         if cnt>3
                             disp('Failure!!!')
                             break
                         end
                    end
                    if isempty(tth)
                        return
                    end
                    p = tth.Position;
                    delete(tth)
                    x = p(1);
                    y = p(2);
                case 'ginput'
                    oldstate=app.contoursCheckBox.Value;
                    changed=false;
                    if oldstate
                        app.contoursCheckBox.Value=false;
                        app.contoursCheckBoxValueChanged;
                        changed=true;
                    end
                    [x,y] = ginput(1);
                case 'drawpoint'
                    rotate3d off
                    oldstate=app.contoursCheckBox.Value;
                    changed=false;
                    roi = drawpoint(app.eem);
                    x = roi.Position(1);
                    y = roi.Position(2);
                    delete(roi)
            end
            
            mindist=@(vec,val) find(ismember(abs(vec-val),min(abs(vec-val))));
            
            app.opts.spectra=[app.data.Em(mindist(app.data.Em,y)) app.data.Ex(mindist(app.data.Ex,x))];
            app.keepselectionCheckBox.Enable=true;
            app.specview
            if changed==true
                app.contoursCheckBox.Value=oldstate;
                app.contoursCheckBoxValueChanged;
            end
        end

        % Value changed function: coloraxisscaleSwitch
        function coloraxisscaleSwitchValueChanged(app, event)
            value = app.coloraxisscaleSwitch.Value;
            set(app.eem,'ColorScale',value)
            set(app.ex,'YScale',value)
            set(app.em,'YScale',value)
            if app.showabsCheckBox.Value
                yyaxis(app.ex,'right')
                set(app.ex,'YScale',value)
                yyaxis(app.ex,'left')
            end
            
        end

        % Value changed function: contoursCheckBox
        function contoursCheckBoxValueChanged(app, event)
            value = app.contoursCheckBox.Value;
            h = get(app.eem,'Children');
            % find the plot that will display the contours.
            idx=contains(arrayfun(@(x) x.Type,h,'UniformOutput',false),'contour');
            try
            if value 
                h(idx).LineStyle='-';
            else
                h(idx).LineStyle='none';
            end
            end
        end

        % Value changed function: contoursEditField
        function contoursEditFieldValueChanged(app, event)
            value = app.contoursEditField.Value;
            h = get(app.eem,'Children');
            set(h(1),'LevelList',linspace(min(h(1).ZData(:),[],'omitnan'),max(h(1).ZData(:),[],'omitnan'),value))
        end

        % Value changed function: Drop_sample
        function Drop_sampleValueChanged(app, event)
            value = app.Drop_sample.Value;
            app.opts.sample = find(matches(app.Drop_sample.Items,value));
            
            if find(matches(app.Drop_sample.Items,value))==1
                app.previousButton.Enable='off';
            else
                app.previousButton.Enable='on';
            end
            if find(matches(app.Drop_sample.Items,value))==app.data.nSample
                app.nextButton.Enable='off';
            else
                app.nextButton.Enable='on';
            end
            app.clearspectra
            app.eemcontourf
            if app.keepselectionCheckBox.Value
                specview(app)
            end
            if app.showabsCheckBox.Value
                app.showabsCheckBoxValueChanged
            end
            if not(app.KeepCLimsCheckBox.Value)
                app.clim_lower.Limits=[0 100];
                app.clim_upper.Limits=[0 100];
                app.clim_lower.Value=0;
                app.clim_upper.Value=100;
            end
        end

        % Value changed function: DropDown_type
        function DropDown_typeValueChanged(app, event)
            value = app.DropDown_type.Value;
            switch value
                case 'data'
                    app.opts.eemmode='data';
                    app.drop_model.Enable='off';
                    app.clim_lower.Enable='on';
                    app.clim_upper.Enable='on';
                case 'model'
                    app.opts.eemmode='data';
                    app.drop_model.Enable='on';
                    app.clim_lower.Enable='on';
                    app.clim_upper.Enable='on';
                case 'residuals'
                    app.opts.eemmode='residual';
                    app.drop_model.Enable='on';
                    app.clim_lower.Enable='off';
                    app.clim_upper.Enable='off';
            end
            app.eemcontourf
        end

        % Value changed function: drop_model
        function drop_modelValueChanged(app, event)
            app.eemcontourf
            app.clearspectra
        end

        % Drop down opening function: drop_model
        function drop_modelDropDownOpening(app, event)
            
        end

        % Value changed function: clim_lower, clim_upper
        function clim_upperValueChanged(app, event)
            lower = app.clim_lower.Value;
            upper = app.clim_upper.Value;
            
                    
            l=prctile(app.eem.UserData,lower);
            u=prctile(app.eem.UserData,upper);
            
            if isinf(abs(l))
                l=0;
            end
            
            newCLim=[l u];
            ConLim=get(app.eem,'CLim');
            try
                set(app.eem,'CLim',newCLim);
            catch
                set(app.eem,'CLim',ConLim);
            end
            
            app.clim_lower.Limits(2)=app.clim_upper.Value;
            
            
            if lower==app.clim_lower.Limits(1)
                app.clim_lower.Limits(1)=app.clim_lower.Limits(1)-app.clim_lower.Limits(1)*0.5;
            end
            
            if lower==app.clim_lower.Limits(2)
                app.clim_lower.Limits(2)=app.clim_lower.Limits(2)+app.clim_lower.Limits(2)*0.5;
            end
            app.drop_colormap;
        end

        % Value changed function: showpeaksCheckBox
        function showpeaksCheckBoxValueChanged(app, event)
            value = app.showpeaksCheckBox.Value;
            % Formatting
            lw=1.5;
            OnOff=value;
            col=[0 0 0];
            mindist=@(vec,val) find(ismember(abs(vec-val),min(abs(vec-val))));
            marker='+';
            markersize=20;
            
            % Definition of peaks
            peaks(1).name=cellstr('B');
            peaks(1).Em=num2cell(305);
            peaks(1).Ex=num2cell(275);
            peaks(2).name=cellstr('T');
            peaks(2).Em=num2cell(340);
            peaks(2).Ex=num2cell(275);
            peaks(3).name=cellstr('A');
            peaks(3).Em=num2cell(400:460);
            peaks(3).Ex=num2cell(260);
            peaks(4).name=cellstr('M');
            peaks(4).Em=num2cell(370:410);
            peaks(4).Ex=num2cell(290:310);
            peaks(5).name=cellstr('C');
            peaks(5).Em=num2cell(420:460);
            peaks(5).Ex=num2cell(320:360);
            peaks(6).name=cellstr('D');
            peaks(6).Em=num2cell(509);
            peaks(6).Ex=num2cell(390);
            peaks(7).name=cellstr('N');
            peaks(7).Em=num2cell(370);
            peaks(7).Ex=num2cell(280);
            if OnOff
                hold(app.eem,'on')
                for nPeak=1:size(peaks,2)
                    if numel(peaks(nPeak).Em)==1&&numel(peaks(nPeak).Ex)==1
                        Labels = peaks(nPeak).name;    %'
                        plot3(app.eem,peaks(nPeak).Ex{1},peaks(nPeak).Em{1},app.eem.ZLim(2),marker,'MarkerSize',markersize,'LineWidth',lw,'Color',col,'DisplayName','PeaksOverlayed')
                        text(app.eem,peaks(nPeak).Ex{1}+0.1,peaks(nPeak).Em{1}+0.1,app.eem.ZLim(2), Labels, 'horizontal','left', 'vertical','bottom','Color',col,'DisplayName','PeaksOverlayed');


                    elseif numel(peaks(nPeak).Ex)==1&&numel(peaks(nPeak).Em)~=1
                        tempEm=app.data.Em(mindist(app.data.Em,peaks(nPeak).Em{1}):mindist(app.data.Em,peaks(nPeak).Em{end}),1);
                        Labels = peaks(nPeak).name;    %'

                        plot3(app.eem,repmat(peaks(nPeak).Ex{1},numel(tempEm)),tempEm,repelem(app.eem.ZLim(2),numel(tempEm),1),'LineWidth',lw,'Color',col,'DisplayName','PeaksOverlayed')
                        text(app.eem,peaks(nPeak).Ex{1}+0.1,peaks(nPeak).Em{1}+0.1,app.eem.ZLim(2), Labels, 'horizontal','left', 'vertical','bottom','Color',col,'DisplayName','PeaksOverlayed');


                    else
                        x1=peaks(nPeak).Ex{1};
                        x2=peaks(nPeak).Ex{end};
                        y1=peaks(nPeak).Em{1};
                        y2=peaks(nPeak).Em{end};
                        x = [x1, x2, x2, x1, x1];
                        y = [y1, y1, y2, y2, y1];
                        Labels = peaks(nPeak).name;
                        plot3(app.eem,x, y , repelem(app.eem.ZLim(2),numel(y),1), 'b-', 'LineWidth', lw,'Color',col,'DisplayName','PeaksOverlayed');
                        text(app.eem,peaks(nPeak).Ex{1}+0.1,peaks(nPeak).Em{1}+0.1,app.eem.ZLim(2), Labels, 'horizontal','left', 'vertical','bottom','Color',col,'DisplayName','PeaksOverlayed');
                    end
                end
                hold(app.eem,'off')
            else
                hc=get(app.eem,'Children');
                for n=1:numel(hc)
                    if ~isempty(strfind(hc(n).DisplayName,'PeaksOverlayed'))
                        %contains(hc(n).DisplayName,{'Raman','Rayleigh'}) % If only everyone would use the latest software
                        delete(hc(n))
                    end
                end
            end
        end

        % Value changed function: showabsCheckBox
        function showabsCheckBoxValueChanged(app, event)
            value = app.showabsCheckBox.Value;
            if value
                yyaxis(app.ex,'right')
                hold(app.ex,'off')
                plot(app.ex,app.data.Abs_wave,app.data.Abs(app.opts.sample,:),'LineStyle','-','Color',[1 .4 .4],'Marker','none','DisplayName','abs')
                set(app.ex,'YColor',[1 .4 .4])
                axis(app.ex,'tight')
                app.ex.Title.String='Ex. / abs. spectra';
                xlabel(app.ex,'Ex / abs. wavelength (nm)')
                ylabel(app.ex,'Absorbance')
                
                app.ex.YLimMode='auto';
                app.ex.YLimitMethod='padded';
                
                yyaxis(app.ex,'left')
            else
                yyaxis(app.ex,'right')
                plot(app.ex,nan,nan,'LineStyle','-','Color','r','Marker','none')
                set(app.ex,'YColor','k','YTick','')
                app.ex.Title.String='Excitation';
            end
            yyaxis(app.ex,'left')
            set(app.ex,'YColor','k')
        end

        % Button pushed function: previousButton
        function previousButtonPushed(app, event)
            
            oidx=find(matches(app.Drop_sample.Items,app.Drop_sample.Value));
            nidx=oidx-1;
            if nidx==0
                nidx=1;
            end
            app.Drop_sample.Value = app.Drop_sample.Items(nidx);

            app.opts.sample = nidx;
            
            if nidx==1
                app.previousButton.Enable='off';
            else
                app.previousButton.Enable='on';
            end
            if nidx==app.data.nSample
                app.nextButton.Enable='off';
            else
                app.nextButton.Enable='on';
            end
            app.clearspectra
            app.eemcontourf
            if app.keepselectionCheckBox.Value
                specview(app)
            end
            if app.showabsCheckBox.Value
                app.showabsCheckBoxValueChanged
            end
            if not(app.KeepCLimsCheckBox.Value)
                app.clim_lower.Limits=[0 100];
                app.clim_upper.Limits=[0 100];
                app.clim_lower.Value=0;
                app.clim_upper.Value=100;
            end
        end

        % Button pushed function: nextButton
        function nextButtonPushed(app, event)
            
            oidx=find(matches(app.Drop_sample.Items,app.Drop_sample.Value));
            nidx=oidx+1;
            app.Drop_sample.Value = app.Drop_sample.Items(nidx);
            app.opts.sample = nidx;
            
            if nidx==1
                app.previousButton.Enable='off';
            else
                app.previousButton.Enable='on';
            end
            if nidx==app.data.nSample
                app.nextButton.Enable='off';
            else
                app.nextButton.Enable='on';
            end
            app.clearspectra
            app.eemcontourf
            if app.keepselectionCheckBox.Value
                specview(app)
            end
            if app.showabsCheckBox.Value
                app.showabsCheckBoxValueChanged
            end
            if not(app.KeepCLimsCheckBox.Value)
                app.clim_lower.Limits=[0 100];
                app.clim_upper.Limits=[0 100];
                app.clim_lower.Value=0;
                app.clim_upper.Value=100;
            end
        end

        % Value changed function: KeeprotationCheckBox
        function KeeprotationCheckBoxValueChanged(app, event)
            
            
        end

        % Value changed function: percentilelimitSlider
        function percentilelimitSliderValueChanged(app, event)
            app.percentilelimitCheckBoxValueChanged;
            
        end

        % Callback function: focuspercentile10CheckBox,
        % percentilelimitSlider
        function percentilelimitCheckBoxValueChanged(app, event)
            value = app.focuspercentile10CheckBox.Value;
            
            
            if value
                val = app.percentilelimitSlider.Value;
                l=val-10;
                u=val+10;
                if l<=0
                    l=1;
                end
                if u>=100
                    u=99;
                end
                try
                app.clim_lower.Value=l;
                app.clim_upper.Value=u;
                app.clim_upperValueChanged;
                catch
                    app.clim_lower.Limits=[0 100];
                    app.clim_upper.Limits=[0 100];
                    app.clim_lower.Value=l;
                    app.clim_upper.Value=u;
                    app.clim_upperValueChanged;
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.vieweemsUIFigure = uifigure('Visible', 'off');
            colormap(app.vieweemsUIFigure, 'parula');
            app.vieweemsUIFigure.Name = 'vieweems: View EEMs and select 2D spectra of FDOM data sets';
            app.GridLayout5 = uigridlayout(app.vieweemsUIFigure);
            app.samplenaviationPanel = uipanel(app.GridLayout5);
            app.samplenaviationPanel.Title = ':::: sample naviation';
            app.GridLayout2 = uigridlayout(app.samplenaviationPanel);
            app.DropDown_type = uidropdown(app.GridLayout2);
            app.DropDown_type.Items = {'data', 'model', 'residuals'};
            app.DropDown_type.ValueChangedFcn = createCallbackFcn(app, @DropDown_typeValueChanged, true);
            app.DropDown_type.Tooltip = {'Choose whether to plot data, modeled data, or residuals. When no models are calculated, only the raw data can be plotted (other options are not shown).'};
            app.DropDown_type.Value = 'data';
            app.drop_model = uidropdown(app.GridLayout2);
            app.drop_model.Items = {};
            app.drop_model.DropDownOpeningFcn = createCallbackFcn(app, @drop_modelDropDownOpening, true);
            app.drop_model.ValueChangedFcn = createCallbackFcn(app, @drop_modelValueChanged, true);
            app.drop_model.Tooltip = {'Chose which model should be used for the modelled or residual data'};
            app.drop_model.Value = {};
            app.previousButton = uibutton(app.GridLayout2, 'push');
            app.previousButton.ButtonPushedFcn = createCallbackFcn(app, @previousButtonPushed, true);
            app.previousButton.Tooltip = {'plot the previous sample in sequence'};
            app.previousButton.Text = 'previous';
            app.nextButton = uibutton(app.GridLayout2, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.nextButton.Tooltip = {'plot the next sample in sequence'};
            app.nextButton.Text = 'next';
            app.Drop_sample = uidropdown(app.GridLayout2);
            app.Drop_sample.ValueChangedFcn = createCallbackFcn(app, @Drop_sampleValueChanged, true);
            app.Drop_sample.Tooltip = {'Select a specific sample from the dataset for plotting'};
            app.spectraPanel = uipanel(app.GridLayout5);
            app.spectraPanel.Title = ':::: spectra';
            app.GridLayout3 = uigridlayout(app.spectraPanel);
            app.ex = uiaxes(app.GridLayout3);
            title(app.ex, 'Ex')
            xlabel(app.ex, 'wavelength (nm)')
            ylabel(app.ex, 'signal')
            zlabel(app.ex, 'Z')
            colormap(app.ex, 'parula')
            app.em = uiaxes(app.GridLayout3);
            title(app.em, 'Em')
            xlabel(app.em, 'wavelength (nm)')
            ylabel(app.em, 'signal')
            zlabel(app.em, 'Z')
            colormap(app.em, 'parula')
            app.excitationemissionmatrixPanel = uipanel(app.GridLayout5);
            app.excitationemissionmatrixPanel.Title = ':::: excitation-emission matrix';
            app.GridLayout4 = uigridlayout(app.excitationemissionmatrixPanel);
            app.eem = uiaxes(app.GridLayout4);
            title(app.eem, 'Title')
            xlabel(app.eem, 'Excitation')
            ylabel(app.eem, 'Emission')
            zlabel(app.eem, 'Z')
            colormap(app.eem, 'parula')
            app.clim_upper = uislider(app.GridLayout4);
            app.clim_upper.ValueChangedFcn = createCallbackFcn(app, @clim_upperValueChanged, true);
            app.clim_upper.Tooltip = {'Set the upper color limit in % of the maximum value.'};
            app.clim_upper.Value = 100;
            app.clim_lower = uislider(app.GridLayout4);
            app.clim_lower.ValueChangedFcn = createCallbackFcn(app, @clim_upperValueChanged, true);
            app.clim_lower.Tooltip = {'Set the lower color limit in % of the minimum value.'};
            app.lowerLabel = uilabel(app.GridLayout4);
            app.lowerLabel.Tooltip = {'Set the lower color limit in % of the minimum value.'};
            app.lowerLabel.Text = 'lower (%)';
            app.upperLabel = uilabel(app.GridLayout4);
            app.upperLabel.Tooltip = {'Set the upper color limit in % of the maximum value.'};
            app.upperLabel.Text = 'upper (%)';
            app.EEMformatPanel = uipanel(app.GridLayout5);
            app.EEMformatPanel.Title = ':::: EEM format';
            app.GridLayout6 = uigridlayout(app.EEMformatPanel);
            app.contoursEditField = uieditfield(app.GridLayout6, 'numeric');
            app.contoursEditField.ValueChangedFcn = createCallbackFcn(app, @contoursEditFieldValueChanged, true);
            app.contoursEditField.Tooltip = {'number of controur lines to be shown (linearly scaled from min to max).'};
            app.contoursEditField.Value = 20;
            app.contoursEditFieldLabel = uilabel(app.GridLayout6);
            app.contoursEditFieldLabel.Tooltip = {'number of controur lines to be shown (linearly scaled from min to max).'};
            app.contoursEditFieldLabel.Text = 'contours';
            app.colormapLabel = uilabel(app.GridLayout6);
            app.colormapLabel.Tooltip = {'Choose the colormap to be used for the EEM.'};
            app.colormapLabel.Text = 'colormap';
            app.drop_colormap = uidropdown(app.GridLayout6);
            app.drop_colormap.Items = {'turbo', 'parula', 'jet', 'hsv', 'pos/neg'};
            app.drop_colormap.ValueChangedFcn = createCallbackFcn(app, @drop_colormapValueChanged, true);
            app.drop_colormap.Tooltip = {'Choose the colormap to be used for the EEM.'};
            app.drop_colormap.Value = 'turbo';
            app.KeeprotationCheckBox = uicheckbox(app.GridLayout6);
            app.KeeprotationCheckBox.ValueChangedFcn = createCallbackFcn(app, @KeeprotationCheckBoxValueChanged, true);
            app.KeeprotationCheckBox.Tooltip = {'Remember rotation of the EEM axis for the next sample.'};
            app.KeeprotationCheckBox.Text = 'Keep rotation';
            app.coloraxisscaleSwitch = uiswitch(app.GridLayout6, 'slider');
            app.coloraxisscaleSwitch.Items = {'linear', 'log'};
            app.coloraxisscaleSwitch.ValueChangedFcn = createCallbackFcn(app, @coloraxisscaleSwitchValueChanged, true);
            app.coloraxisscaleSwitch.Enable = 'off';
            app.coloraxisscaleSwitch.Visible = 'off';
            app.coloraxisscaleSwitch.Tooltip = {'Experimental feature: Choose log- or linear-scaled EEM color axis and 2D scan y-scaling.'};
            app.coloraxisscaleSwitch.Value = 'linear';
            app.contoursCheckBox = uicheckbox(app.GridLayout6);
            app.contoursCheckBox.ValueChangedFcn = createCallbackFcn(app, @contoursCheckBoxValueChanged, true);
            app.contoursCheckBox.Tooltip = {'plot EEM contours (number of contours is specified to the left)'};
            app.contoursCheckBox.Text = 'contours';
            app.contoursCheckBox.Value = true;
            app.showpeaksCheckBox = uicheckbox(app.GridLayout6);
            app.showpeaksCheckBox.ValueChangedFcn = createCallbackFcn(app, @showpeaksCheckBoxValueChanged, true);
            app.showpeaksCheckBox.Tooltip = {'Show pre-defined peaks in the EEM.'};
            app.showpeaksCheckBox.Text = 'show peaks';
            app.percentilelimitSliderLabel = uilabel(app.GridLayout6);
            app.percentilelimitSliderLabel.Visible = 'off';
            app.percentilelimitSliderLabel.Text = 'percentile limit';
            app.percentilelimitSlider = uislider(app.GridLayout6);
            app.percentilelimitSlider.Limits = [12 88];
            app.percentilelimitSlider.MajorTicks = [0 25 50 75 100];
            app.percentilelimitSlider.ValueChangedFcn = createCallbackFcn(app, @percentilelimitSliderValueChanged, true);
            app.percentilelimitSlider.ValueChangingFcn = createCallbackFcn(app, @percentilelimitCheckBoxValueChanged, true);
            app.percentilelimitSlider.MinorTicks = [];
            app.percentilelimitSlider.Visible = 'off';
            app.percentilelimitSlider.Tooltip = {'Adjust which part of the EEM is focussed upon. (when checkmark above is set).'};
            app.percentilelimitSlider.Value = 50;
            app.focuspercentile10CheckBox = uicheckbox(app.GridLayout6);
            app.focuspercentile10CheckBox.ValueChangedFcn = createCallbackFcn(app, @percentilelimitCheckBoxValueChanged, true);
            app.focuspercentile10CheckBox.Visible = 'off';
            app.focuspercentile10CheckBox.Tooltip = {'Experimental feature. Allows the colormap to be focussed on a narrow range of values. Can be useful to inspect only ceratain parts of an EEM.'};
            app.focuspercentile10CheckBox.Text = 'focus percentile (+/- 10%)';
            app.KeepCLimsCheckBox = uicheckbox(app.GridLayout6);
            app.KeepCLimsCheckBox.Tooltip = {'Keep color limits for the next sample. Can be useful if fine adjustments are made and should be carried over all the time. Limits are stored in % of the max and min values.'};
            app.KeepCLimsCheckBox.Text = 'Keep CLims';
            app.plotcontrolPanel = uipanel(app.GridLayout5);
            app.plotcontrolPanel.Title = ':::: plot control';
            app.GridLayout = uigridlayout(app.plotcontrolPanel);
            app.keepselectionCheckBox = uicheckbox(app.GridLayout);
            app.keepselectionCheckBox.Tooltip = {'When enabled, the wavelength selection for plotting 2D spectra is remembered and the respective spectra are plotted automatically for the next sample.'; ''; 'Only available after spectra have been plotted (and thus values have been stored).'};
            app.keepselectionCheckBox.Text = 'keep selection';
            app.holdspectraCheckBox = uicheckbox(app.GridLayout);
            app.holdspectraCheckBox.Tooltip = {'When enabled, emission and excitation spectra are superimposed when plotting several of them for the same sample.'};
            app.holdspectraCheckBox.Text = 'hold spectra';
            app.showabsCheckBox = uicheckbox(app.GridLayout);
            app.showabsCheckBox.ValueChangedFcn = createCallbackFcn(app, @showabsCheckBoxValueChanged, true);
            app.showabsCheckBox.Tooltip = {'When enabled, absorbance spectra are plotted or superimposed in the excitation scan axis.'};
            app.showabsCheckBox.Text = 'show abs.';
            app.spectraButton = uibutton(app.GridLayout, 'push');
            app.spectraButton.ButtonPushedFcn = createCallbackFcn(app, @spectraButtonPushed, true);
            app.spectraButton.Tooltip = {'After clicking this button, hover over a point in the EEM at which you''d like to extract spectra.'; ''; 'Note: When hovering over the EEM, wait ca. 1s for the coordinates to be registered. Clicking with the mouse botton is not required (nor is it helpful).'};
            app.spectraButton.Text = 'spectra';
            % Show the figure after all components are created
            app.vieweemsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vieweems(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.vieweemsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.vieweemsUIFigure)
        end
    end
end