classdef dreeminstall < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        drEEMtoolboxinstallerUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        Panel                          matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        UninstallButton                matlab.ui.control.Button
        OpendocumentationButton        matlab.ui.control.Button
        console                        matlab.ui.control.ListBox
        InstalllogListBoxLabel         matlab.ui.control.Label
        latestVersion                  matlab.ui.control.ListBox
        DownloadinstalllatestButton    matlab.ui.control.Button
        installedVersions              matlab.ui.control.ListBox
        InstalledversionsLabel         matlab.ui.control.Label
        LatestversionGitLabLabel       matlab.ui.control.Label
        drEEMtoolboxLabel              matlab.ui.control.Label
        logo                           matlab.ui.control.Image
    end

    
    properties (Access = private)
        existing % Description
        online
    end

    methods (Access = private)
        function online=isOnline(app)
            if ispc
                C = evalc('!ping -n 1 8.8.8.8');
            elseif isunix
                C = evalc('!ping -c 1 8.8.8.8');
            end

            if contains(C,{'No route to host','unreachable'})
                online=false;
            else
                online=true;
            end

        end

        function updateconsole(app,message,type)
            arguments
                app
                message (1,:) {mustBeText}
                type (1,:) {mustBeText,mustBeMember(type,["success","warning","error","information"])} = "information"
            end
            if not(exist("type","var"))
                color='k';
                fw='normal';
            else
                switch type
                    case 'success'
                        color=[0         0.4         0.2];
                        fw='bold';
                    case 'warning'
                        color=[1         0.6           0];
                        fw='bold';
                    case 'error'
                        color=[1         0.4         0.4];
                        fw='bold';
                    case 'information'
                        color=[.2         0.2         0.2];
                        fw='normal';
                end
            end

            n=numel(app.console.Items);
            n=n+1;
            time=char(datetime('now'));time=time(end-7:end);
            app.console.Items{1,end+1} = [num2str(n),':  ',time,': ',message];
            s = uistyle(FontColor=color,FontWeight=fw);
            addStyle(app.console,s,"item",n)
            scroll(app.console, 'bottom');drawnow
        end
        function repro = fetchRepository(app)
            directory='https://gitlab.com/dreem/dreem-2/-/raw/main/@drEEMtoolbox/versions.txt';
            vhist=webread(directory);
            cellvhist=textscan(vhist,'%s%s','Delimiter',';');

            repro=table;
            repro.version=cellvhist{1};
            repro.url=cellvhist{2};
        end

        function versionout = fetchExisting(app)
            versionout=ver;
            versionout=versionout(arrayfun(@(x) contains(x.Name,'drEEM'),versionout));
            versionout=rmfield(versionout,{'Date','Release','Name'});
        end

        function outcome=compareVersions(app,existing,online)
            ov=online.version{1}; % latest online version
            ev={existing.Version}; % installed version(s)

            % digest version numbers
            ov=cellfun(@(x) str2double(x),strsplit(ov,'.'));
            for j=1:length(ev)
                evd(j,:)=cellfun(@(x) str2double(x),strsplit(ev{j},'.'));
            end

            % Compare versions
            update=true;
            for j=1:height(evd)
                if ov(1)>=evd(j,1)
                    if ov(2)>=evd(j,2)
                        if ov(3)>evd(j,3)
                            update(j)=true;
                        else
                            update(j)=false;
                        end
                    else
                        update(j)=false;
                    end
                else
                    update(j)=false;
                end
            end

            if any(update)
                outcome='outdated';
            else
                outcome='up-to-date';
            end
        end

        function uninstall(app)
            %%
            % Current path list (cell array)
            curPath = path;
            curPath=textscan(curPath,'%s','delimiter',pathsep);
            curPath=curPath{:};
            curPath=cellfun(@(x) [x,filesep],curPath,UniformOutput=false);
            % The subset with dreem (all lower) in the path
            foldermatches=contains(lower(curPath),'dreem');
            dreemFolders=curPath(foldermatches);

            % Make a table with the drEEM folders
            listing=table;
            for j=1:numel(dreemFolders)
                files=dir(dreemFolders{j});
                listing=[listing;struct2table(files)];
            end

            % In case this there are no folders, just exit
            if isempty(listing)
                return
            end
            listing(listing.isdir==1,:)=[]; % deletes the directory entries

            % These three m-files target drEEM-2, drEEM > 0.4 and drEEM < 0.4
            targetMfiles={'viewmodels.mlapp','randinitanal.m','nmodel.m'};

            % subset the listing to the folders that contain the entries
            listing=listing(matches(listing.name,targetMfiles),:);

            % Now, isolate the root folder of each installation. This is done assuming
            % that each m-file is one up from the root and the last folder is deleted
            % from each path
            for j=1:height(listing)
                if isunix
                    temp=strsplit(listing.folder{j},filesep); % Split the path with filesep
                    temp=temp(1:end-1); % delete the last folder
                    temp(cellfun(@(x) isempty(x),temp))=[]; % delete empty cells
                    temp=cellfun(@(x) [filesep,x],temp,uni=false); % fuse the path back together
                else
                    temp=strsplit(listing.folder{j},filesep); % Split the path with filesep
                    temp=temp(1:end-1); % delete the last folder
                    temp(cellfun(@(x) isempty(x),temp))=[]; % delete empty cells
                    temp=cellfun(@(x) [x,filesep],temp,uni=false); % fuse the path back together
                end
                listing.folder{j}=[temp{:}]; % paste it back in
            end
            % Isolate the unique paths (there will be multiples for each) and then prepare
            % for the deletion
            deleteThese=curPath(contains(curPath,unique(listing.folder)));
            % delete the drEEM toolboxes
            if not(isempty(deleteThese))
                rmpath(deleteThese{:})  % This removes old drEEM folders
                rehash
                app.updateconsole('drEEM folders found and removed from the path',"success")
                try
                    savepath
                catch
                    app.updateconsole('Could not save the current searchpath. You will have to reinstall drEEM after restarting Matlab.','warning')
                end
                app.startup(true)
            else
                app.updateconsole('No action taken (no drEEM folders on path).')
                app.startup(true)
            end

        end

        function tbPath=downloadAndUnpackToolbox(app,url)
            temp=strsplit(url,'/');temp=temp{end};temp=strsplit(temp,'.zip');temp=temp{1};
            app.updateconsole(['Downloading toolbox (',url,')'])
            try
                app.updateconsole(['Unzipping (',userpath,filesep,temp,filesep,')'])
                unzip(url,userpath)
            catch
                try
                    app.updateconsole(['Unzipping (',userpath,filesep,temp,filesep,')'])
                    o = weboptions('CertificateFilename','');
                    websave('dreemtemp',url,o);
                    if isfile("dreemtemp.zip")
                        unzip('dreemtemp.zip',userpath);
                        delete('dreemtemp.zip')
                    else
                        app.updateconsole('Could not find download file ("dreemtemp.zip"). Did the download work?','error')
                    end
                catch
                    app.updateconsole('Could not download the requested version of drEEM. Contact support if the issue persists.','error')
                end
            end
            tbPath=[userpath,filesep,temp,filesep];
        end

        function installroutine(app,folder,backup)
            p=genpath(folder);
            addpath(p)
            rehash
            try
                savepath
            catch
                app.updateconsole('Could not save the current searchpath. You will have to reinstall drEEM after restarting Matlab.','warning')
            end

            try
                v=ver;
                tb=v(arrayfun(@(x) contains(x.Name,'drEEM'),v));
                if isempty(tb)
                    app.rollback
                    error('After install, ver() does not reveal drEEM toolbox.')
                end
                if str2double(tb.Version(1))==0
                    app.updateconsole('Legacy mode: No automated Matlab compatibility checks.','warning')
                else
                    drEEMtoolbox.versionRequires
                end
                app.startup(true)
                app.updateconsole('Install successful.',"success")
                app.OpendocumentationButton.Enable="on";
                app.OpendocumentationButton.Visible="on";
            catch ME
                app.startup(true)
                app.updateconsole(['Changes reverted, error: ',ME.message],'error')
                app.OpendocumentationButton.Enable="off";
                app.OpendocumentationButton.Visible="off";
                path(backup)
                
            end
            
        end


        function startup(app,silent)
            arguments
                app
                silent (1,1) {mustBeNumericOrLogical} = false
            end
            app.latestVersion.Items=app.fetchRepository.version;
            if not(silent),app.updateconsole('Fetched the version list from GitLab'),end
            app.latestVersion.Items=app.latestVersion.Items(1);
            app.latestVersion.Value=app.latestVersion.Items{1};

            app.installedVersions.Items=sort(categorical({app.fetchExisting.Version}),"descend");
            if not(isempty(app.installedVersions.Items))
                app.installedVersions.Value=app.installedVersions.Items{1};
            end
            if not(silent),app.updateconsole('Retreived the installed drEEM versions (if > 0.5.x)'),end

            app.existing=app.fetchExisting;
            app.online=app.fetchRepository;
            if isempty(app.existing)
                if not(silent),app.updateconsole('No existing drEEM installation detected.'),end
                scenario='clean install';
            elseif isscalar(app.existing)
                if not(silent),app.updateconsole('Existing drEEM installation detected.'),end
                scenario=app.compareVersions(app.existing,app.online);
            elseif length(app.existing)>1
                if not(silent),app.updateconsole('Several existing drEEM installations detected.'),end
                scenario=app.compareVersions(app.existing,app.online);
            end

            switch scenario
                case 'up-to-date'
                    if not(silent),app.updateconsole('Toolbox is up-to-date'),end
                    app.DownloadinstalllatestButton.Enable="off";
                    app.UninstallButton.Enable="on";
                    app.OpendocumentationButton.Enable="on";
                case 'outdated'
                    if not(silent),app.updateconsole('Toolbox update available'),end
                    app.DownloadinstalllatestButton.Enable="on";
                    app.UninstallButton.Enable="on";
                    app.OpendocumentationButton.Enable="on";
                case 'clean install'
                    app.DownloadinstalllatestButton.Enable="on";
                    app.UninstallButton.Enable="off";
                    app.OpendocumentationButton.Enable="off";
            end
        end
        
        function rollback(app)
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                app.drEEMtoolboxinstallerUIFigure.Visible=false;
                movegui(app.drEEMtoolboxinstallerUIFigure,'center')
                imageURL = webread('https://dreem.openfluor.org/assets/header_image.jpg');
                imageURL = imresize(imageURL, 1.1);
                app.logo.ImageSource=imageURL;
                if app.isOnline
                    app.updateconsole('Matlab can reach the internet')
                    app.drEEMtoolboxinstallerUIFigure.Visible=true;
                else
                    delete(app)
                    error('Not online... Come back when the WiFi cable is plugged in.')
                end
                app.startup
            catch ME
                delete(app)
                error(ME.message)
            end


        end

        % Button pushed function: DownloadinstalllatestButton
        function DownloadinstalllatestButtonPushed(app, event)
            app.uninstall
            folder=app.downloadAndUnpackToolbox(app.online.url{1});
            % if isunix
            %     folder='/Users/urban/Documents/MATLAB/drEEM-2.0';
            % else
            %     folder='C:\Users\urbw\OneDrive - Danmarks Tekniske Universitet\Documents 1\Work\git\drEEM-2.0';
            % end
            app.installroutine(folder,path)
        end

        % Button pushed function: OpendocumentationButton
        function OpendocumentationButtonPushed(app, event)
            doc dreem
        end

        % Button pushed function: UninstallButton
        function UninstallButtonPushed(app, event)
            app.uninstall
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.drEEMtoolboxinstallerUIFigure = uifigure('Visible', 'off');
            app.drEEMtoolboxinstallerUIFigure.Name = 'drEEM toolbox installer';
            app.GridLayout = uigridlayout(app.drEEMtoolboxinstallerUIFigure);
            app.logo = uiimage(app.GridLayout);
            app.logo.Tooltip = {'https://dreem.openfluor.org/'};
            app.logo.URL = 'https://dreem.openfluor.org/';
            app.logo.AltText = 'drEEM project website';
            app.drEEMtoolboxLabel = uilabel(app.GridLayout);
            app.drEEMtoolboxLabel.Tooltip = {''};
            app.drEEMtoolboxLabel.Text = {'drEEM toolbox'; 'decomposition routines for excitation-emission matricies'};
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.LatestversionGitLabLabel = uilabel(app.GridLayout2);
            app.LatestversionGitLabLabel.Text = 'Latest version (GitLab):';
            app.InstalledversionsLabel = uilabel(app.GridLayout2);
            app.InstalledversionsLabel.Text = 'Installed version(s):';
            app.installedVersions = uilistbox(app.GridLayout2);
            app.installedVersions.Items = {};
            app.installedVersions.Value = {};
            app.DownloadinstalllatestButton = uibutton(app.GridLayout2, 'push');
            app.DownloadinstalllatestButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadinstalllatestButtonPushed, true);
            app.DownloadinstalllatestButton.Text = 'Download + install latest';
            app.latestVersion = uilistbox(app.GridLayout2);
            app.latestVersion.Items = {};
            app.latestVersion.Value = {};
            app.InstalllogListBoxLabel = uilabel(app.GridLayout2);
            app.InstalllogListBoxLabel.Text = 'Install  log';
            app.console = uilistbox(app.GridLayout2);
            app.console.Items = {''};
            app.console.Value = {};
            app.OpendocumentationButton = uibutton(app.GridLayout2, 'push');
            app.OpendocumentationButton.ButtonPushedFcn = createCallbackFcn(app, @OpendocumentationButtonPushed, true);
            app.OpendocumentationButton.Enable = 'off';
            app.OpendocumentationButton.Text = 'Open documentation';
            app.UninstallButton = uibutton(app.GridLayout2, 'push');
            app.UninstallButton.ButtonPushedFcn = createCallbackFcn(app, @UninstallButtonPushed, true);
            app.UninstallButton.Text = 'Uninstall';
            % Show the figure after all components are created
            app.drEEMtoolboxinstallerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dreeminstall

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.drEEMtoolboxinstallerUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.drEEMtoolboxinstallerUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.drEEMtoolboxinstallerUIFigure)
        end
    end
end